classdef OrientationAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button_ExportData              matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        SpectraTypeDropDownLabel       matlab.ui.control.Label
        DropDown_SpectraType           matlab.ui.control.DropDown
        BrushDataPanel                 matlab.ui.container.Panel
        Button_ReadBrush               matlab.ui.control.Button
        Button_DeleteRow               matlab.ui.control.Button
        Button_Clearall                matlab.ui.control.Button
        Button_IntensityPlot           matlab.ui.control.Button
        CheckBox_OverlayExp            matlab.ui.control.CheckBox
        FShiftLabel                    matlab.ui.control.Label
        EditField_FShift               matlab.ui.control.NumericEditField
        OrientationMapPanel            matlab.ui.container.Panel
        SelectedLabel                  matlab.ui.control.Label
        SelectedIndex                  matlab.ui.control.EditField
        GridEditFieldLabel             matlab.ui.control.Label
        NGrid                          matlab.ui.control.NumericEditField
        PhiSliceEditFieldLabel         matlab.ui.control.Label
        PhiSlice                       matlab.ui.control.EditField
        PsiSliceEditFieldLabel         matlab.ui.control.Label
        PsiSlice                       matlab.ui.control.EditField
        ThetaSliceEditFieldLabel       matlab.ui.control.Label
        ThetaSlice                     matlab.ui.control.EditField
        PhiContourSliceEditFieldLabel  matlab.ui.control.Label
        PhiContourSlice                matlab.ui.control.EditField
        PsiContourSliceEditFieldLabel  matlab.ui.control.Label
        PsiContourSlice                matlab.ui.control.EditField
        ThetaContourSliceEditFieldLabel  matlab.ui.control.Label
        ThetaContourSlice              matlab.ui.control.EditField
        IsovaluesEditFieldLabel        matlab.ui.control.Label
        Isovalues                      matlab.ui.control.EditField
        VersionLabel                   matlab.ui.control.Label
        Button_DrawMap                 matlab.ui.control.Button
        StyleDropDownLabel             matlab.ui.control.Label
        DropDown_DrawStyle             matlab.ui.control.DropDown
        Button_RefineSolutionSpace     matlab.ui.control.Button
        RefinementScoreLabel           matlab.ui.control.Label
        DropDown_ScoreType             matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        ParentGUI   % handles of parent
        fileChooser % a place to keep file chooser handle
        CurrentFig  % the current figure that can be brushed and read
        
        Data_Brush  % the data saved from brushed region
        N_Brished   % # of brush selected region
        SelectedRow % The current selected row on the UITable
        
        Data_Spec   % Spectral Data (SFG or 2D SFG)
        Response_V  % the response tensor [3^3 or 3^5, 1] for the brushed region
        EJLR_T      % 3D grid points of the rotational tensor [4*NG^3,3^3 or 3^5]
        IntGrid     % The Signal grid (EJLR*Beta) of the given structure [4*NG^3,N_Brished]
        
        ColorOrder % predefined colors for scatter markers
    end

    methods (Access = private)
    
        function Update_OrientTable(app)
            %% Reorginze Data_Brush
            Data_Table = reshape([app.Data_Brush(:).TableRow],4,[])';
            Row_Color  = reshape([app.Data_Brush(:).Color],3,[])';
            
            %% Initial vlues of the Orientation inputs
            ColumnName     = {       '', 'Intensity', 'Target','Weight'};
            ColumnFormat   = {'logical',      'char',   'bank',  'bank'};
            ColumnEditable = [     true,       false,     true,    true];
            ColumnWidth    = {       20,         65,        45,      50};
            
            app.UITable.ColumnName      = ColumnName;
            app.UITable.ColumnFormat    = ColumnFormat;
            app.UITable.ColumnEditable  = ColumnEditable;
            app.UITable.ColumnWidth     = ColumnWidth;
            app.UITable.RowName         = 'numbered';
            app.UITable.BackgroundColor = Row_Color;
            app.UITable.Data            = Data_Table;
        end
        
        function Read_SpecData(app)
            SpecType = app.DropDown_SpectraType.Value;
            switch SpecType
                case 'SFG'
                    app.Data_Spec = app.ParentGUI.Data_SFG;
                case '2DSFG'
                    app.Data_Spec = app.ParentGUI.Data_2DSFG;
            end
        end
        
        function Update_EJLR_Tensor_Grid(app)
            COSMOSS_Input = app.ParentGUI.Parse_GUI;
            SpecType      = app.DropDown_SpectraType.Value;
            N_Grid        = app.NGrid.Value;
            
            app.EJLR_T = EJLR_Tensor(COSMOSS_Input,SpecType,N_Grid);
            
            % Update the 3D grid points for the exixiting slections
            if ~isempty(app.Response_V)
                app.IntGrid  = app.EJLR_T * app.Response_V; % size = [4*NG^3, N_Brush];
            end
        end
        
        function Init_GUI(app)
            % clean up Data_Brush
            app.N_Brished  = 0;
            app.Data_Brush = [];
            app.Data_Brush.BrushedIdx = [];
            app.Data_Brush.FreqSub    = [];
            app.Data_Brush.FreqInd    = [];
            app.Data_Brush.Color      = [1,1,1];
            app.Data_Brush.hScatter   = [];
            app.Data_Brush.TableRow   = {'','','',''};
            Update_OrientTable(app)
            
            % Reset variables
            Read_SpecData(app)
            app.Response_V = [];
            app.IntGrid    = [];
            
            % make default figure
            cla(app.UIAxes,'reset')

            COSMOSS_Input = app.ParentGUI.Parse_GUI;
            COSMOSS_Input.PlotCursor = 0;
            
            SpecType = app.DropDown_SpectraType.Value;
            switch SpecType
                case 'SFG'
                    Plot1D(app.UIAxes,app.Data_Spec,COSMOSS_Input);
                    
                case '2DSFG'
                    CVL = Conv2D(app.Data_Spec.SpectraGrid,COSMOSS_Input);
                    CVL.FilesName = app.ParentGUI.Structure.FilesName; % pass filesname for figure title
                    Plot2D(app.UIAxes,CVL,COSMOSS_Input,app.Data_Spec.Response.SpecType);

                    app.UIAxes.Title.String = '';
                    colorbar(app.UIAxes,'off')
            end
        end
        
        function CopyExp2DContour(app,hAx)
            % Load 2D Experiment 
            Default_Path = '~/Desktop';
            
            [FilesName,PathName,~] = uigetfile({'*.fig','2D SFG fig'; ...
                                                '*,*','All Files'},...
                                                'Select inputs',Default_Path);
            disp([FilesName,' loaded...'])
            hF_Exp = openfig([PathName,FilesName]);
            hContour_Exp = findobj(hF_Exp,'Type','Contour');
            hContour_Exp = hContour_Exp(1);
            
            copyobj(hContour_Exp,hAx)
            close(hF_Exp)
        end
        
    end

    methods (Access = public)
    
        function O = Parse_GUI(app)
            O.Selection_Ind = str2num(app.SelectedIndex.Value);
            O.PhiS          = str2num(app.PhiSlice.Value);
            O.PsiS          = str2num(app.PsiSlice.Value);
            O.ThetaS        = str2num(app.ThetaSlice.Value);
            O.PsiCS         = str2num(app.PsiContourSlice.Value);
            O.PhiCS         = str2num(app.PhiContourSlice.Value);
            O.ThetaCS       = str2num(app.ThetaContourSlice.Value);
            O.IsoV          = str2num(app.Isovalues.Value);
        end
        
        function VolumVisulization(app,event,Phi,Psi,Theta,V)
            hF  = figure;
            hAx = axes('Parent',hF);
            
            % Apply color map
            [CMAP,~] = SelectColormap(app.ParentGUI.Parse_GUI.CMAP_Index_2D);
            colormap(hAx,CMAP)
            % Read GUI inputs
            O = Parse_GUI(app);
            
            switch app.DropDown_DrawStyle.Value
                case 'Theta-Psi'
                    [~,CutAngleInd] = min(abs((Phi-O.PhiS)));
                    X = Psi;
                    Y = Theta;
                    Z = squeeze(V(:,CutAngleInd,:));
                    contourf(hAx,X,Y,Z,20)
            
                    hAx.XLabel.String = '\psi';
                    hAx.YLabel.String = '\theta';
                    IndexStr    = [' of selection #: ',app.SelectedIndex.Value];
                    ProjectStr  = [' (',XLabel,',',YLabel,')'];
                    CutAngleStr = [' map cut at \phi=',num2str(O.PhiS),'^{\circ}'];
                    TitleStr = [SpecType,ProjectStr,CutAngleStr,IndexStr];
            
                case 'Theta-Phi'
                    [~,CutAngleInd] = min(abs((Psi-O.PsiS)));
                    X = Phi;
                    Y = Theta;
                    Z = squeeze(V(:,:,CutAngleInd));
                    contourf(hAx,X,Y,Z,20)
            
                    hAx.XLabel.String = '\phi';
                    hAx.YLabel.String = '\theta';
                    IndexStr    = [' of selection #: ',app.SelectedIndex.Value];
                    ProjectStr  = [' (',XLabel,',',YLabel,')'];
                    CutAngleStr = [' map cut at \psi=',num2str(O.PsiS),'^{\circ}'];
                    TitleStr = [SpecType,ProjectStr,CutAngleStr,IndexStr];
            
                case 'Slice'
                    hold(hAx,'on')
                        % Slice
                        hSlice = slice(hAx,Phi,Theta,Psi,V,O.PhiS,O.ThetaS,O.PsiS);
                        N_Slice = length(hSlice);
                        for j = 1:N_Slice
                            hSlice(j).EdgeColor = 'none';
                            hSlice(j).FaceAlpha = 0.5;
                        end
            
                        % Contour Slcie Projection
                        cvals = linspace(-1,1,40);
                        %contourslice(hAx,Phi,Theta,Psi,V,O.PhiCS,O.ThetaCS,O.PsiCS,cvals);
                        hCS_Phi   = contourslice(hAx,Phi,Theta,Psi,V,O.PhiCS,       [],     [],cvals);
                        hCS_Theta = contourslice(hAx,Phi,Theta,Psi,V,     [],O.ThetaCS,     [],cvals);
                        hCS_Psi   = contourslice(hAx,Phi,Theta,Psi,V,     [],       [],O.PsiCS,cvals);
                        
                        % project the contour slices to the boundary
                        N_CS_Phi = length(hCS_Phi);
                        for i = 1:N_CS_Phi
                            hCS_Phi(i).XData = ones(size(hCS_Phi(i).XData)).*0;
                        end
            
                        N_CS_Theta = length(hCS_Theta);
                        for j = 1:N_CS_Theta
                            hCS_Theta(j).YData = ones(size(hCS_Theta(j).YData)).*180;
                        end
                        
                        N_CS_Psi = length(hCS_Psi);
                        for k = 1:N_CS_Psi
                            hCS_Psi(k).ZData = ones(size(hCS_Psi(k).ZData)).*0;
                        end
                        
                        %% Draw a visual guide of the projections
                        %Cut_Origin    = [O.PhiCS,O.ThetaCS,O.PsiCS];
                        %Phi_Cut_End   = [    360,O.ThetaCS,O.PsiCS];
                        %Theta_Cut_End = [O.PhiCS,      180,O.PsiCS];
                        %Psi_Cut_End   = [O.PhiCS,O.ThetaCS,      0];
                        %% Arror head
                        %Arror_W = 2;
                        %Arror_H = 2*Arror_W;
                        %arrow3(hAx,Cut_Origin,  Phi_Cut_End,'c-4',Arror_W,Arror_H,[],[],0.5)
                        %arrow3(hAx,Cut_Origin,Theta_Cut_End,'c-4',Arror_W,Arror_H,[],[],0.5)
                        %arrow3(hAx,Cut_Origin,  Psi_Cut_End,'c-4',Arror_W,Arror_H,[],[],0.5)
                        
                        % isosurf
                        N_IsoV = length(O.IsoV);
                        if ~eq(N_IsoV,0)
                            CV = linspace(-1,1,64);
                            for i =1:N_IsoV
                                disp(event.Source.Text)

                                IsoV = O.IsoV(i);
                                [~,IsoColorInd] = min(abs(CV-IsoV));
                                IsoColor = CMAP(IsoColorInd,:);
            
                                fv = isosurface(Phi,Theta,Psi,V,IsoV);
                                P  = patch(hAx,fv,...
                                          'FaceColor',IsoColor,...
                                          'FaceAlpha',abs(IsoV),...
                                          'EdgeColor','none');
                                isonormals(Phi,Theta,Psi,V,P)
                            end
                        end
                    hold(hAx,'off')
            
                    camlight 
                    camlight('headlight')
                    %camlight(-80,-10) 
                    lighting gouraud
            
                    hAx.XLabel.String = '\phi';
                    hAx.YLabel.String = '\theta';
                    hAx.ZLabel.String = '\psi';
                    hAx.Box = 'on';
                    hAx.XGrid = 'on';
                    hAx.YGrid = 'on';
                    hAx.ZGrid = 'on';
                    hAx.XLim = [0,360];
                    hAx.YLim = [0,180];
                    hAx.ZLim = [0,360];
                    IndexStr = app.SelectedIndex.Value;
                    TitleStr = [app.DropDown_SpectraType.Value,' of selection #: ',IndexStr];
            end
            
            Cmax = max(abs(caxis(hAx)));
            caxis(hAx,[-Cmax,Cmax])
            
            hAx.FontSize = 16;
            hAx.Title.String = TitleStr;
            view(hAx,3)
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                AssignParent(app,varargin)
                TriggerSource = varargin{3};
            else
                TriggerSource = 'SFG';
                disp('Running Orientation Analysis in the stand alone mode...')
            end
            
            % Initalized trigger source 
            app.DropDown_SpectraType.Value = TriggerSource;
            
            % initialize the UIAxes and Table
            Init_GUI(app)
            
            % generate EJLR_T
            Update_EJLR_Tensor_Grid(app)
            
            % Pre-define distinguishable colors for the scatter markers
            app.ColorOrder = distinguishable_colors(20,{'r','b','k','w'});
        end

        % Button pushed function: Button_IntensityPlot
        function ButtonPushed_IntensityPlot(app, event)
            app.CurrentFig = figure;
            hAx = axes('Parent',app.CurrentFig);
            hold(hAx,'on')
            
            % Overlay Exp contour if needed
            if app.CheckBox_OverlayExp.Value
                CopyExp2DContour(app,hAx)
            end
            
            % Prep parameters before drawing
            Read_SpecData(app)
            Spec = app.Data_Spec;
            COSMOSS_Input = app.ParentGUI.Parse_GUI;
            FShift = app.EditField_FShift.Value;
            switch app.DropDown_SpectraType.Value
                case 'SFG'
                    COSMOSS_Input.Lineshape = 'Lorentzian';
                    COSMOSS_Input.PlotNorm  = 1;
                    
                    Spec.Response1D = Spec.Res_Int;
                    Plot1D(hAx,Spec,COSMOSS_Input);
                    
                    ScatterX = Spec.freq_OneD;
                    ScatterY = Spec.Res_Int;
                    ScatterX(eq(ScatterY,0)) = [];
                    ScatterY(eq(ScatterY,0)) = [];
                    
                    X = ScatterX - FShift; % apply Freq. Shift
                    Y = ScatterY;
                    Y = Y./max(abs(Y(:)));
                    hold(hAx,'on')
                    scatter(hAx,X,Y,100,'filled','MarkerFaceColor',[0,0,0])  
                    hAx.YLim = [0,1.1];
                    
                case '2DSFG'
                    SIntGrid = Spec.Response.IntGrid;
                    COSMOSS_Input.Lineshape = 'No Conv';         
                    CVL = Conv2D(SIntGrid,COSMOSS_Input);
                    CVL.FilesName = ''; % pass filesname for figure title
                    
                    
                    switch COSMOSS_Input.Pathway
                        case 'EA'
                           % Force R3/NR3 to be positive
                           CVL.selected         = -CVL.selected;
                           CVL.selected_No_Conv = -CVL.selected_No_Conv;
                           
                           CMAP  = 'hot';
                           CAxis = 'Asym';
                           
                        case 'All'
                           % Change colormap to be symmetric
                           CMAP  = 'cool';
                           CAxis = 'Sym';
                           
                        otherwise 
                           % Use positive only colormap
                           CMAP  = 'hot';
                           CAxis = 'Asym';
                    end
                    
                    % apply Frequency Shift to match the experiment
                    CVL.FreqRange = CVL.FreqRange - FShift; 
                    TitleStr = ['2DSFG Int, FShift = ',num2str(FShift),'cm^{-1}'];
                    Plot2D(hAx,CVL,COSMOSS_Input,TitleStr);
                    hAx.Color = [0.3,0.3,0.3];
                    app.CurrentFig.InvertHardcopy = 'off'; % preserve background color
                    
                    colormap(hAx,CMAP)
                    % Decide colormaping range
                    CRange = caxis(hAx);
                    C_Max = max(abs(CRange));
                    switch CAxis
                        case 'Asym'
                           caxis(hAx,[0,C_Max])
                        case 'Sym'
                           caxis(hAx,[-C_Max,C_Max])
                    end
                
            end
            hold(hAx,'off')
        end

        % Button pushed function: Button_ExportData
        function ButtonPushed_ExportData(app, event)
            Data_Orient.Data_Spec   = app.Data_Spec;
            Data_Orient.Data_Brush  = app.Data_Brush;
            Data_Orient.Data_Table  = app.UITable.Data;
            Data_Orient.Response_V  = app.Response_V;
            Data_Orient.IntGrid     = app.IntGrid;
            Data_Orient.EJLR_T      = app.EJLR_T;
            Data_Orient.app         = app;
            
            assignin('base','Data_Orient',Data_Orient)
            disp('Updated Data_Orient exported!')
        end

        % Button pushed function: Button_ReadBrush
        function ButtonPushed_ReadBrush(app, event)
            N = app.N_Brished + 1; % update counter
            app.N_Brished = N;
            
            % Read brush data from the Intensity Plot
            hF  = app.CurrentFig;
            hS  = findobj(hF,'Type','Scatter');
            hAx = findobj(hF,'Type','Axes');
            
            BrushedIdx = logical(hS.BrushData);
            FShift = app.EditField_FShift.Value;
            switch app.DropDown_SpectraType.Value
                case 'SFG'
                    app.UIAxes.XLim = hAx.XLim;
                    
                    Sort_Ex_F1 = app.Data_Spec.H.Sort_Ex_F1;
                    if iscolumn(Sort_Ex_F1)
                        Sort_Ex_F1 = Sort_Ex_F1';
                    end
                    FreqSub     = hS.XData(BrushedIdx)'+FShift;
                    [~,FreqInd] = min(abs( bsxfun(@minus,FreqSub,Sort_Ex_F1) ),[],2);
                    Beta = app.Data_Spec.MolFrame(:,FreqInd);
                    Beta = sum(Beta,2);
                    
                    Int = sum(Beta.^2,1);
                    
                    Scatter_X = hS.XData(BrushedIdx)';
                    Scatter_Y = zeros(size(Scatter_X));
                    MarkerSize = 150;
                    
                case '2DSFG'
                    app.UIAxes.XLim = hAx.XLim;
                    app.UIAxes.YLim = hAx.YLim;
                    
                    Response = app.Data_Spec.Response;
                    
                    FreqSub    = [hS.YData(BrushedIdx)'+FShift,hS.XData(BrushedIdx)'+FShift]; % [Pump,Probe]
                    SparseMax  = Response.SparseMax;
                    FreqInd    = sub2ind([SparseMax,SparseMax],FreqSub(:,1),FreqSub(:,2));
                    
                    Beta =        Response.Beta.R1(FreqInd,:)   ;
                    Beta = [Beta; Response.Beta.R2(FreqInd,:) ;];
                    Beta = [Beta;-Response.Beta.R3(FreqInd,:) ;];
                    Beta = [Beta; Response.Beta.NR1(FreqInd,:);];
                    Beta = [Beta; Response.Beta.NR2(FreqInd,:);];
                    Beta = [Beta;-Response.Beta.NR3(FreqInd,:);];
                    Beta = sum(Beta,1)';
                    
                    Int =       Response.IntGrid.R1( FreqInd)  ;
                    Int = [Int; Response.IntGrid.R2( FreqInd);];
                    Int = [Int;-Response.IntGrid.R3( FreqInd);];
                    Int = [Int; Response.IntGrid.NR1(FreqInd);];
                    Int = [Int; Response.IntGrid.NR2(FreqInd);];
                    Int = [Int;-Response.IntGrid.NR3(FreqInd);];
                    Int = full(sum(Int,1));
                    
                    Scatter_X = hS.XData(BrushedIdx)';
                    Scatter_Y = hS.YData(BrushedIdx)';
                    MarkerSize = 150;
            end
            
            % Save data of selected region to GUI Properties
            app.Response_V(:,N) = Beta;              % size = [3^3 or 3^5, 1]
            app.IntGrid(:,N)    = app.EJLR_T * Beta; % size = [4*NG^3, 1];

            % Update selected region on GUI axis
            if ge(N,size(app.ColorOrder,1))
                N = mod(N,size(app.ColorOrder,1));
                BrushedColor = app.ColorOrder(N,:);
            else
                BrushedColor = app.ColorOrder(N,:);
            end
            hold(app.UIAxes,'on')
                hScatter = scatter(app.UIAxes,...
                                   Scatter_X,Scatter_Y,...
                                   MarkerSize,...
                                   'filled',...
                                   'MarkerFaceColor',BrushedColor);
            hold(app.UIAxes,'off')
            
            % export Data_Brush to app and update the UITable
            app.Data_Brush(N).BrushedIdx = BrushedIdx;
            app.Data_Brush(N).FreqSub    = FreqSub;
            app.Data_Brush(N).FreqInd    = FreqInd;
            app.Data_Brush(N).Color      = BrushedColor;
            app.Data_Brush(N).hScatter   = hScatter;
            app.Data_Brush(N).TableRow   = {true,sprintf('%3.2e',Int),1,1};
            Update_OrientTable(app)
        end

        % Button pushed function: Button_Clearall
        function ButtonPushed_ClearAll(app, event)
            Init_GUI(app)
            disp('GUI refreshed and started over!')
        end

        % Button pushed function: Button_DeleteRow
        function ButtonPushed_DeleteRow(app, event)
            Delete_Ind = app.SelectedRow;
            % update counter
            app.N_Brished = app.N_Brished - length(Delete_Ind);
            % delete scatter plot obj
            delete(app.Data_Brush(Delete_Ind).hScatter)
            % update Data_Brush
            app.Data_Brush(Delete_Ind) = [];
            Update_OrientTable(app)
            % delete the Response Tensor and the Intensity Grid of the selected rgion
            app.Response_V(:,Delete_Ind)= [];
            app.IntGrid(:,Delete_Ind)   = [];
            
        end

        % Button pushed function: Button_DrawMap
        function ButtonPushed_DrawMap(app, event)
            % Prepare grid points
            N_Grid = app.NGrid.Value;
            Phi   = linspace(0,360,N_Grid*2)'; % phi
            Theta = linspace(0,180,N_Grid)'  ; % theta
            Psi   = linspace(0,360,N_Grid*2)'; % psi
            
            % Select the coresponding Intensity Grids
            O = Parse_GUI(app);
            IntensityGridVec = sum(app.IntGrid(:,O.Selection_Ind),2);
            
            % Since "EJLR_Grid" use [Phi,Theta,Psi] = meshgrid(phi,theta,psi);
            V    = reshape(IntensityGridVec,length(Theta),length(Phi),length(Psi)); % [Theta,Phi,Psi]
            maxV = max(abs(V(:)));
            V_N  = V./maxV;
            
            VolumVisulization(app,event,Phi,Psi,Theta,V_N)
        end

        % Cell selection callback: UITable
        function CellSelection_UITable(app, event)
            RowInd = event.Indices(:,1);
            app.SelectedRow = RowInd;
            
            IndexStr = num2str(RowInd');
            IndexStr = regexprep(IndexStr,'\ \ ',',');
            app.SelectedIndex.Value = IndexStr;
        end

        % Value changed function: DropDown_SpectraType
        function ValueChanged_SpectraType(app, event)
            Init_GUI(app)
            Read_SpecData(app)
            Update_EJLR_Tensor_Grid(app)
            disp('EJLR rotational tensor grid updated!')
        end

        % Value changed function: NGrid
        function ValueChanged_GridNumber(app, event)
            Update_EJLR_Tensor_Grid(app)
            disp('EJLR rotational tensor grid updated!')
        end

        % Button pushed function: Button_RefineSolutionSpace
        function Button_RefineSolutionSpacePushed(app, event)
            TableData = app.UITable.Data;
            Selected  = cell2mat(TableData(       :,1)) ;
            Target    = cell2mat(TableData(Selected,3))';
            Weight    = cell2mat(TableData(Selected,4))';
            
            SigSelected = app.IntGrid(:,Selected);

            ScoreType = app.DropDown_ScoreType.Value;
            switch ScoreType
                case 'Sign'
                    ScoreGird = Score_Sign(SigSelected,Target);
                case 'Intensity'
                    ScoreGird = Score_Int(SigSelected,Target,Weight);
            end
            
            % Prepare grid points
            N_Grid = app.NGrid.Value;
            Phi    = linspace(0,360,N_Grid*2)'; % phi
            Theta  = linspace(0,180,N_Grid)'  ; % theta
            Psi    = linspace(0,360,N_Grid*2)'; % psi
            
            Score3D = reshape(ScoreGird,length(Theta),length(Phi),length(Psi)); % [Theta,Phi,Psi]   
            VolumVisulization(app,event,Phi,Psi,Theta,Score3D)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Orientation Analysis';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_ExportData, true);
            app.Button_ExportData.Text = 'Export Data';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; 'Intensity'; 'Ratio'; 'Weight'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @CellSelection_UITable, true);
            app.SpectraTypeDropDownLabel = uilabel(app.UIFigure);
            app.SpectraTypeDropDownLabel.Text = 'Spectra Type';
            app.DropDown_SpectraType = uidropdown(app.UIFigure);
            app.DropDown_SpectraType.Items = {'SFG', '2DSFG'};
            app.DropDown_SpectraType.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_SpectraType, true);
            app.DropDown_SpectraType.Value = 'SFG';
            app.BrushDataPanel = uipanel(app.UIFigure);
            app.BrushDataPanel.Title = 'Brush Data';
            app.Button_ReadBrush = uibutton(app.BrushDataPanel, 'push');
            app.Button_ReadBrush.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_ReadBrush, true);
            app.Button_ReadBrush.Text = 'Read Brush';
            app.Button_DeleteRow = uibutton(app.BrushDataPanel, 'push');
            app.Button_DeleteRow.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_DeleteRow, true);
            app.Button_DeleteRow.Text = 'Delete Row';
            app.Button_Clearall = uibutton(app.BrushDataPanel, 'push');
            app.Button_Clearall.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_ClearAll, true);
            app.Button_Clearall.Text = 'Clear all';
            app.Button_IntensityPlot = uibutton(app.BrushDataPanel, 'push');
            app.Button_IntensityPlot.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_IntensityPlot, true);
            app.Button_IntensityPlot.Text = 'Intensity Plot';
            app.CheckBox_OverlayExp = uicheckbox(app.BrushDataPanel);
            app.CheckBox_OverlayExp.Text = 'Overlay Exp';
            app.FShiftLabel = uilabel(app.BrushDataPanel);
            app.FShiftLabel.Text = 'F Shift:';
            app.EditField_FShift = uieditfield(app.BrushDataPanel, 'numeric');
            app.OrientationMapPanel = uipanel(app.UIFigure);
            app.OrientationMapPanel.Title = 'Orientation Map';
            app.SelectedLabel = uilabel(app.OrientationMapPanel);
            app.SelectedLabel.Text = 'Selected';
            app.SelectedIndex = uieditfield(app.OrientationMapPanel, 'text');
            app.SelectedIndex.Value = 'none';
            app.GridEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.GridEditFieldLabel.Text = '# Grid';
            app.NGrid = uieditfield(app.OrientationMapPanel, 'numeric');
            app.NGrid.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_GridNumber, true);
            app.NGrid.Value = 10;
            app.PhiSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.PhiSliceEditFieldLabel.Text = 'Phi Slice';
            app.PhiSlice = uieditfield(app.OrientationMapPanel, 'text');
            app.PsiSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.PsiSliceEditFieldLabel.Text = 'Psi Slice';
            app.PsiSlice = uieditfield(app.OrientationMapPanel, 'text');
            app.ThetaSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.ThetaSliceEditFieldLabel.Text = 'Theta Slice';
            app.ThetaSlice = uieditfield(app.OrientationMapPanel, 'text');
            app.PhiContourSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.PhiContourSliceEditFieldLabel.Text = 'Phi Contour Slice';
            app.PhiContourSlice = uieditfield(app.OrientationMapPanel, 'text');
            app.PsiContourSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.PsiContourSliceEditFieldLabel.Text = 'Psi Contour Slice';
            app.PsiContourSlice = uieditfield(app.OrientationMapPanel, 'text');
            app.ThetaContourSliceEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.ThetaContourSliceEditFieldLabel.Text = 'Theta Contour Slice';
            app.ThetaContourSlice = uieditfield(app.OrientationMapPanel, 'text');
            app.IsovaluesEditFieldLabel = uilabel(app.OrientationMapPanel);
            app.IsovaluesEditFieldLabel.Text = 'Isovalues';
            app.Isovalues = uieditfield(app.OrientationMapPanel, 'text');
            app.Isovalues.Value = '-.75,-.5,.5,.75';
            app.VersionLabel = uilabel(app.UIFigure);
            app.VersionLabel.Text = 'V1.4';
            app.Button_DrawMap = uibutton(app.UIFigure, 'push');
            app.Button_DrawMap.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_DrawMap, true);
            app.Button_DrawMap.Text = 'Draw Map';
            app.StyleDropDownLabel = uilabel(app.UIFigure);
            app.StyleDropDownLabel.Text = 'Style';
            app.DropDown_DrawStyle = uidropdown(app.UIFigure);
            app.DropDown_DrawStyle.Items = {'Slice', 'Theta-Psi', 'Theta-Phi'};
            app.DropDown_DrawStyle.Value = 'Slice';
            app.Button_RefineSolutionSpace = uibutton(app.UIFigure, 'push');
            app.Button_RefineSolutionSpace.ButtonPushedFcn = createCallbackFcn(app, @Button_RefineSolutionSpacePushed, true);
            app.Button_RefineSolutionSpace.Text = 'Refine solution space';
            app.RefinementScoreLabel = uilabel(app.UIFigure);
            app.RefinementScoreLabel.Text = 'Refinement Score:';
            app.DropDown_ScoreType = uidropdown(app.UIFigure);
            app.DropDown_ScoreType.Items = {'Intensity', 'Sign'};
            app.DropDown_ScoreType.Value = 'Sign';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Region of selection')
            xlabel(app.UIAxes, 'Probe (cm^{-1})')
            ylabel(app.UIAxes, 'Pump (cm^{-1})')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OrientationAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end