classdef ResVis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ModepropertiesPanel            matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        SpecTypeDropDownLabel          matlab.ui.control.Label
        SpecType                       matlab.ui.control.DropDown
        SortbyDropDownLabel            matlab.ui.control.Label
        SortbyDropDown                 matlab.ui.control.DropDown
        SortButton                     matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        VisulizationoptionsPanel       matlab.ui.container.Panel
        ModetypeDropDownLabel          matlab.ui.control.Label
        Mode_Type                      matlab.ui.control.DropDown
        SelectedmodesEditFieldLabel    matlab.ui.control.Label
        Mode_Ind                       matlab.ui.control.EditField
        TDV_Plot                       matlab.ui.control.CheckBox
        ScaleEditFieldLabel            matlab.ui.control.Label
        TDV_Scale                      matlab.ui.control.NumericEditField
        Raman_Plot                     matlab.ui.control.CheckBox
        ScaleEditField_2Label          matlab.ui.control.Label
        Raman_Scale                    matlab.ui.control.NumericEditField
        RepresentationDropDownLabel    matlab.ui.control.Label
        Raman_Type                     matlab.ui.control.DropDown
        TDV_Normalize                  matlab.ui.control.CheckBox
        Raman_Normalize                matlab.ui.control.CheckBox
        Plot_EigVec                    matlab.ui.control.CheckBox
        ScaleEditField_3Label          matlab.ui.control.Label
        EigVec_Scale                   matlab.ui.control.NumericEditField
        RepresentationDropDown_2Label  matlab.ui.control.Label
        EigVec_Conv                    matlab.ui.control.DropDown
        DrawResponseButton             matlab.ui.control.Button
        ExportDataButton               matlab.ui.control.Button
        V10Label                       matlab.ui.control.Label
    end


    properties (Access = public)
        ParentGUI   % GUI handle of the parent GUI
        fileChooser % a place to keep file chooser handle
        TableData 
        SpecData
        Structure
    end

    methods (Access = private)
    
        function Init(app)             
            RefreshButtonPushed(app,'')
        end
        
        function RefreshOff(app)       
            app.RefreshButton.FontColor = [0,0,0];
        end
    end

    methods (Access = public)
    
        function O = Parse_GUI(app)
            O.SpecType        =         app.SpecType.Value       ;
            O.Mode_Type       =         app.Mode_Type.Value      ; 
            O.Mode_Ind        = str2num(app.Mode_Ind.Value)      ;
            O.TDV_Plot        =         app.TDV_Plot.Value       ;
            O.TDV_Scale       =         app.TDV_Scale.Value      ;
            O.TDV_Normalize   =         app.TDV_Normalize.Value  ;
            O.Raman_Plot      =         app.Raman_Plot.Value     ;
            O.Raman_Scale     =         app.Raman_Scale.Value    ;
            O.Raman_Type      =         app.Raman_Type.Value     ;
            O.Raman_Normalize =         app.Raman_Normalize.Value;
            O.Plot_EigVec     =         app.Plot_EigVec.Value    ;
            O.EigVec_Conv     =         app.EigVec_Conv.Value    ;
            O.EigVec_Scale    =         app.EigVec_Scale.Value   ;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                AssignParent(app,varargin)
                TriggerSource = varargin{3};
            else
                TriggerSource = 'SFG';
                disp('Running Orientation Analysis in the stand alone mode...')
            end
            
            % Initalized trigger source 
            app.SpecType.Value = TriggerSource;
            
            % initialize the UIAxes and Table
            Init(app)
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            Data_ResVis.TableData   = app.TableData;
            
            assignin('base','Data_ResVis',Data_ResVis)
            disp('Updated Data_ResVis exported!')
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.Structure = app.ParentGUI.Structure;
            
            disp(app.SpecType.Value)
            switch app.SpecType.Value
                case 'FTIR'
                    app.SpecData = app.ParentGUI.Data_FTIR;
                case 'SFG'
                    app.SpecData = app.ParentGUI.Data_SFG;
            end
            
            app.TableData  = Update_Modes_Table(app.SpecData);
            %% Initial table format            
            app.UITable.ColumnName      = app.TableData.ModeList.Name;
            app.UITable.ColumnFormat    = app.TableData.ModeList.Format;           
            app.UITable.ColumnWidth     = app.TableData.ModeList.Width;
            app.UITable.RowName         = 'numbered';
            app.UITable.ColumnEditable  = false;
            app.UITable.Data            = app.TableData.ModeList.Data;
            
            app.SortbyDropDown.Items = app.TableData.ModeList.Name;
            
            RefreshOff(app)
        end

        % Button pushed function: DrawResponseButton
        function DrawResponseButtonPushed(app, event)
            Update_Modes_Figure(app.Parse_GUI,app.Structure,app.SpecData);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            RowInd = event.Indices(:,1);
            IndexStr = num2str(RowInd');
            IndexStr = regexprep(IndexStr,'\ \ ',',');
            app.Mode_Ind.Value = IndexStr;
        end

        % Button pushed function: SortButton
        function SortButtonPushed(app, event)
            Data        = app.UITable.Data;
            SortColName = app.SortbyDropDown.Value;
            SortInd     = strcmp(app.SortbyDropDown.Items,SortColName);
            SortCol     = abs(cell2mat(Data(:,SortInd)));
            switch SortColName
                case {'Index','Freq.'}
                    [~,Ind] = sort(SortCol,'ascend');
                otherwise
                    [~,Ind] = sort(SortCol,'descend');
            end
            app.UITable.Data = Data(Ind,:);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Response Visulization';
            app.ModepropertiesPanel = uipanel(app.UIFigure);
            app.ModepropertiesPanel.Title = 'Mode properties';
            app.UITable = uitable(app.ModepropertiesPanel);
            app.UITable.ColumnName = {'Index'; 'Freq.'; 'P %'; 'Sig.'; '|Mu|'; 'Mu_x'; 'Mu_y'; 'Mu_z'; '|A|'; 'A1'; 'A2'; 'A3'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SpecTypeDropDownLabel = uilabel(app.ModepropertiesPanel);
            app.SpecTypeDropDownLabel.Text = 'Spec. Type';
            app.SpecType = uidropdown(app.ModepropertiesPanel);
            app.SpecType.Items = {'FTIR', 'SFG'};
            app.SpecType.Value = 'SFG';
            app.SortbyDropDownLabel = uilabel(app.ModepropertiesPanel);
            app.SortbyDropDownLabel.Text = 'Sort by:';
            app.SortbyDropDown = uidropdown(app.ModepropertiesPanel);
            app.SortButton = uibutton(app.ModepropertiesPanel, 'push');
            app.SortButton.ButtonPushedFcn = createCallbackFcn(app, @SortButtonPushed, true);
            app.SortButton.Text = 'Sort';
            app.RefreshButton = uibutton(app.ModepropertiesPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.VisulizationoptionsPanel = uipanel(app.UIFigure);
            app.VisulizationoptionsPanel.Title = 'Visulization options';
            app.ModetypeDropDownLabel = uilabel(app.VisulizationoptionsPanel);
            app.ModetypeDropDownLabel.Text = 'Mode type';
            app.Mode_Type = uidropdown(app.VisulizationoptionsPanel);
            app.Mode_Type.Items = {'Local modes', 'Exciton modes'};
            app.Mode_Type.Value = 'Exciton modes';
            app.SelectedmodesEditFieldLabel = uilabel(app.VisulizationoptionsPanel);
            app.SelectedmodesEditFieldLabel.Text = 'Selected modes:';
            app.Mode_Ind = uieditfield(app.VisulizationoptionsPanel, 'text');
            app.Mode_Ind.Value = '1';
            app.TDV_Plot = uicheckbox(app.VisulizationoptionsPanel);
            app.TDV_Plot.Text = 'Transition dipoles';
            app.TDV_Plot.Value = true;
            app.ScaleEditFieldLabel = uilabel(app.VisulizationoptionsPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.TDV_Scale = uieditfield(app.VisulizationoptionsPanel, 'numeric');
            app.TDV_Scale.Value = 1;
            app.Raman_Plot = uicheckbox(app.VisulizationoptionsPanel);
            app.Raman_Plot.Text = 'Raman tensors';
            app.Raman_Plot.Value = true;
            app.ScaleEditField_2Label = uilabel(app.VisulizationoptionsPanel);
            app.ScaleEditField_2Label.Text = 'Scale';
            app.Raman_Scale = uieditfield(app.VisulizationoptionsPanel, 'numeric');
            app.Raman_Scale.Value = 1;
            app.RepresentationDropDownLabel = uilabel(app.VisulizationoptionsPanel);
            app.RepresentationDropDownLabel.Text = 'Representation';
            app.Raman_Type = uidropdown(app.VisulizationoptionsPanel);
            app.Raman_Type.Items = {'Arrow', 'Disk', 'Ellipsoid', 'Hyper Ellipsoid'};
            app.Raman_Type.Value = 'Hyper Ellipsoid';
            app.TDV_Normalize = uicheckbox(app.VisulizationoptionsPanel);
            app.TDV_Normalize.Text = 'Normalize';
            app.Raman_Normalize = uicheckbox(app.VisulizationoptionsPanel);
            app.Raman_Normalize.Text = 'Normalize';
            app.Plot_EigVec = uicheckbox(app.VisulizationoptionsPanel);
            app.Plot_EigVec.Text = 'Eigenvectors';
            app.ScaleEditField_3Label = uilabel(app.VisulizationoptionsPanel);
            app.ScaleEditField_3Label.Text = 'Scale';
            app.EigVec_Scale = uieditfield(app.VisulizationoptionsPanel, 'numeric');
            app.EigVec_Scale.Value = 1;
            app.RepresentationDropDown_2Label = uilabel(app.VisulizationoptionsPanel);
            app.RepresentationDropDown_2Label.Text = 'Representation';
            app.EigVec_Conv = uidropdown(app.VisulizationoptionsPanel);
            app.EigVec_Conv.Items = {'Sphere', 'TDV', 'Raman'};
            app.EigVec_Conv.Value = 'Sphere';
            app.DrawResponseButton = uibutton(app.UIFigure, 'push');
            app.DrawResponseButton.ButtonPushedFcn = createCallbackFcn(app, @DrawResponseButtonPushed, true);
            app.DrawResponseButton.Text = 'Draw Response';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.V10Label = uilabel(app.UIFigure);
            app.V10Label.Text = 'V 1.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResVis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end