classdef COSMOSS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_Structure                 matlab.ui.container.Panel
        ListBox_Model                   matlab.ui.control.ListBox
        Button_SelectModel              matlab.ui.control.Button
        PathwayCutoffLabel              matlab.ui.control.Label
        EditField_FeynmannCutoff        matlab.ui.control.NumericEditField
        Panel_ExpSetup                  matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DTab_Exp1D                      matlab.ui.container.Tab
        UITable_1D                      matlab.ui.control.Table
        DTab_Exp2D                      matlab.ui.container.Tab
        UITable_2D                      matlab.ui.control.Table
        Panel_FigOpt                    matlab.ui.container.Panel
        CheckBox_SaveFig                matlab.ui.control.CheckBox
        EditField_SavePath              matlab.ui.control.EditField
        ExisitingfigureCheckBox         matlab.ui.control.CheckBox
        fighandleLabel                  matlab.ui.control.Label
        hFEditField                     matlab.ui.control.EditField
        TabGroup_FigOption              matlab.ui.container.TabGroup
        OneDTab                         matlab.ui.container.Tab
        cm1Label1_1D                    matlab.ui.control.Label
        cm1Label2_1D                    matlab.ui.control.Label
        FrequencyrangeMinLabel_2        matlab.ui.control.Label
        EditField_FreqMin_1D            matlab.ui.control.NumericEditField
        MaxLabel_2                      matlab.ui.control.Label
        EditField_FreqMax_1D            matlab.ui.control.NumericEditField
        LineShapeLabel_2                matlab.ui.control.Label
        DropDown_LineShape_1D           matlab.ui.control.DropDown
        WidthLabel_2                    matlab.ui.control.Label
        EditField_Width_1D              matlab.ui.control.NumericEditField
        CheckBox_Sticks_1D              matlab.ui.control.CheckBox
        CheckBox_Normalize_1D           matlab.ui.control.CheckBox
        CheckBox_Cursor_1D              matlab.ui.control.CheckBox
        SignalTypeLabel_2               matlab.ui.control.Label
        DropDown_SignalType_1D          matlab.ui.control.DropDown
        CheckBox_IntArea_1D             matlab.ui.control.CheckBox
        CheckBox_YSym_1D                matlab.ui.control.CheckBox
        TwoDTab                         matlab.ui.container.Tab
        ColormapLabel                   matlab.ui.control.Label
        DropDown_ColorMap_2D            matlab.ui.control.DropDown
        SignalTypeLabel                 matlab.ui.control.Label
        DropDown_SignalType_2D          matlab.ui.control.DropDown
        SpectralTypeLabel               matlab.ui.control.Label
        DropDown_SpectralType_2D        matlab.ui.control.DropDown
        PathwayLabel                    matlab.ui.control.Label
        DropDown_Pathway_2D             matlab.ui.control.DropDown
        cm1Label1_2D                    matlab.ui.control.Label
        cm1Label2_2D                    matlab.ui.control.Label
        FrequencyrangeMinLabel          matlab.ui.control.Label
        EditField_FreqMin_2D            matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        EditField_FreqMax_2D            matlab.ui.control.NumericEditField
        LineShapeLabel                  matlab.ui.control.Label
        DropDown_LineShape_2D           matlab.ui.control.DropDown
        WidthLabel                      matlab.ui.control.Label
        EditField_Width_2D              matlab.ui.control.NumericEditField
        CheckBox_Normalize_2D           matlab.ui.control.CheckBox
        CheckBox_Cursor_2D              matlab.ui.control.CheckBox
        ContourEditFieldLabel           matlab.ui.control.Label
        EditField_Contour_2D            matlab.ui.control.NumericEditField
        Panel_AnalysisTools             matlab.ui.container.Panel
        TabGroupTools                   matlab.ui.container.TabGroup
        Tab_FTIR                        matlab.ui.container.Tab
        Button_TS_FTIR                  matlab.ui.control.Button
        ResponseVisulizationButton_FTIR  matlab.ui.control.Button
        Tab_SFG                         matlab.ui.container.Tab
        Button_TS_SFG                   matlab.ui.control.Button
        Button_Orient_1DSFG             matlab.ui.control.Button
        ResponseVisulizationButton_SFG  matlab.ui.control.Button
        Tab_2DIR                        matlab.ui.container.Tab
        Button_TS_2DIR                  matlab.ui.control.Button
        Button_Cut_2DIR                 matlab.ui.control.Button
        Tab_2DSFG                       matlab.ui.container.Tab
        Button_Orient_2DSFG             matlab.ui.control.Button
        Button_TS_2DSFG                 matlab.ui.control.Button
        Button_Cut_2DSFG                matlab.ui.control.Button
        Button_FTIR                     matlab.ui.control.Button
        Button_SFG                      matlab.ui.control.Button
        Button_2DIR                     matlab.ui.control.Button
        Button_2DSFG                    matlab.ui.control.Button
        Button_ExportData               matlab.ui.control.Button
        Panel_SampleSymmetry            matlab.ui.container.Panel
        RotationalAverageDropDownLabel  matlab.ui.control.Label
        DropDown_RotAvg                 matlab.ui.control.DropDown
        MirrorPlaneDropDownLabel        matlab.ui.control.Label
        DropDown_MirrorPlane            matlab.ui.control.DropDown
        Panel_EnsembleAverage           matlab.ui.container.Panel
        CheckBox_Sampling               matlab.ui.control.CheckBox
        SamplingLabel                   matlab.ui.control.Label
        EditField_SampleN               matlab.ui.control.NumericEditField
        CheckBox_DynamicFigUpdate       matlab.ui.control.CheckBox
        CheckBox_Continue               matlab.ui.control.CheckBox
        CheckBox_ViewSampling           matlab.ui.control.CheckBox
        FluctuationCorrelationLabel     matlab.ui.control.Label
        EditField_FluctuationCorrelation  matlab.ui.control.NumericEditField
        DiagonalDisordercm1Label        matlab.ui.control.Label
        EditField_DD                    matlab.ui.control.NumericEditField
        OffdiagonalDisordercm1Label     matlab.ui.control.Label
        EditField_ODD                   matlab.ui.control.NumericEditField
        isotopeDilutionCheckBox         matlab.ui.control.CheckBox
        DropDown_ViewSamplingMode       matlab.ui.control.DropDown
        Version                         matlab.ui.control.Label
        CurrentStructureButton          matlab.ui.control.Button
    end


    properties (Access = public)
        resourcePaths % A cell array to save Path for codes running COSMOSS
        GUI_Tag       % Tag for GUI recognition 
        hModel        % handle of modeling GUI
        hOrient       % handle of Oriental Analysis GUI
        hResVis       % handle of Response Visulization GUI
        fileChooser   % for mock model in UItest
        UUID          % unique ID to identify childrens
        
        Structure  % StructureData
        Data_FTIR  % FTIR
        Data_SFG   % SFG
        Data_2DIR  % 2D IR
        Data_2DSFG % 2D SFG
        
        Refresh_FTIR  % Refresh FTIR tag
        Refresh_SFG   % Refresh SFG tag
        Refresh_2DIR  % Refresh 2DIR tag
        Refresh_2DSFG % Refresh 2DSFG tag
        RefreshSource % Refresh off trigger source
    end

    methods (Access = public)
    
        function RefreshOn(app)
            app.Refresh_FTIR  = 1;
            app.Refresh_SFG   = 1;
            app.Refresh_2DIR  = 1;
            app.Refresh_2DSFG = 1;
            
            app.Button_FTIR.FontColor  = [0,0,1];
            app.Button_SFG.FontColor   = [0,0,1];
            app.Button_2DIR.FontColor  = [0,0,1];
            app.Button_2DSFG.FontColor = [0,0,1];
            
            % auto refresh the analysis tool GUI if any            
            if isa(app.hResVis,'handle')
                disp('updateing the data in Orentation Analysis GUI...')
                app.hResVis.RefreshButton.FontColor  = [0,0,1];
            end
            
        end
        
        function RefreshOff(app)
            switch app.RefreshSource
                case 'FTIR'
                    app.Refresh_FTIR = 0;
                    app.Button_FTIR.FontColor = [0,0,0];
                case 'SFG'
                    app.Refresh_SFG = 0;
                    app.Button_SFG.FontColor = [0,0,0];
                case '2DIR'
                    app.Refresh_2DIR = 0;
                    app.Button_2DIR.FontColor = [0,0,0];
                case '2DSFG'
                    app.Refresh_2DSFG = 0;
                    app.Button_2DSFG.FontColor = [0,0,0];
            end
        end

        function Output = Parse_GUI(app)
            Output = Parse_COSMOSS_GUI(app);
        end
        
    end

    methods (Access = private)
    
        function Continue = CheckStructue(app)
            Continue = 1;
            if isempty(app.Structure)
                warning('No structre model is defined, run model construction first...')
                Continue = 0;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %% generate an UUID
            tmp = java.util.UUID.randomUUID;
            app.UUID = string(tmp.toString);
            
            %% Setup paths
            [pathCOSMOSS,~,~] = fileparts(which('COSMOSS'));
            app.resourcePaths{1} = pathCOSMOSS;
            app.resourcePaths{2} = genpath([pathCOSMOSS,'/GUI_resources']);
            app.resourcePaths{3} = genpath([pathCOSMOSS,'/MoleculeConstruction']);
            app.resourcePaths{4} = genpath([pathCOSMOSS,'/SpecFunc']);
            app.resourcePaths{5} = genpath([pathCOSMOSS,'/AnalysisTools']);
            
            for i = 1:length(app.resourcePaths)
                addpath(app.resourcePaths{i})
            end
             
            % Initialize COSMOSS 
            Init_COSMOSS_GUI(app); 
            
            RefreshOn(app) % Initialize refresh tags
            
            % Use mocked fileChooser when performing UItests
            if nargin > 1
                app.fileChooser = varargin{1};
            else
                app.fileChooser = DefaultFileChooser;
            end
        end

        % Button pushed function: Button_SelectModel
        function Button_SelectModelPushed(app, event)
            SelectedModel = app.ListBox_Model.Value;
            [fhModel,ModelList] = StructureModel(SelectedModel);
            
            app.hModel = feval(fhModel,'COSMOSS',app);
            disp(['COSMOSS now links to model ' ModelList{SelectedModel}])
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            ExportGUIData(app)
        end

        % Button pushed function: Button_FTIR
        function Button_FTIRPushed(app, event)
            if CheckStructue(app)
                app.RefreshSource = 'FTIR';
                RefreshOff(app)
                
                app.TabGroup.SelectedTab = app.DTab_Exp1D;
                app.TabGroup_FigOption.SelectedTab = app.OneDTab;
                app.TabGroupTools.SelectedTab = app.Tab_FTIR;
                app.Data_FTIR = iteration(app,@FTIR_Main,'oneD');
                app.Data_FTIR.SpecType = 'FTIR';
            end
        end

        % Button pushed function: Button_SFG
        function Button_SFGPushed(app, event)
            if CheckStructue(app)
                app.RefreshSource = 'SFG';
                RefreshOff(app)
                
                app.TabGroup.SelectedTab = app.DTab_Exp1D;
                app.TabGroup_FigOption.SelectedTab = app.OneDTab;
                app.TabGroupTools.SelectedTab = app.Tab_SFG;
                app.Data_SFG = iteration(app,@OneDSFG_Main,'oneD');
                app.Data_SFG.SpecType = 'SFG';
            end
        end

        % Button pushed function: Button_2DIR
        function Button_2DIRPushed(app, event)
            if CheckStructue(app)
                app.TabGroup.SelectedTab = app.DTab_Exp2D;
                app.TabGroup_FigOption.SelectedTab = app.TwoDTab;
                app.TabGroupTools.SelectedTab = app.Tab_2DIR;
                % Determine if re-calculate
                if app.Refresh_2DIR
                    output = iteration(app,@TwoDIR_Main_Sparse,'twoD');
                    SpectraGrid = output.SpectraGrid;
                    Response    = output.Response;
                    CVL         = output.CVL;
                    app.RefreshSource = '2DIR';
                    RefreshOff(app)
                else
                    Response    = app.Data_2DIR.Response;
                    SpectraGrid = app.Data_2DIR.SpectraGrid;
                    
                    I = Parse_GUI(app);
                    CVL = Conv2D(SpectraGrid,I);
                    CVL.FilesName = app.Structure.FilesName; % pass filesname for figure title
                    
                    if app.ExisitingfigureCheckBox.Value
                        hAx_final = findobj(evalin('base',app.hFEditField.Value),'Type','Axes');
                    else
                        hF_final  = figure;
                        hAx_final = axes('Parent',hF_final);
                    end
                    Plot2D(hAx_final,CVL,I,Response.SpecType);
                end
                
                % Output
                app.Data_2DIR.SpectraGrid = SpectraGrid;
                app.Data_2DIR.Response    = Response;
                app.Data_2DIR.CVL         = CVL;
                app.Data_2DIR.SpecType    = '2DIR';
            end
        end

        % Button pushed function: Button_2DSFG
        function Button_2DSFGPushed(app, event)
            if CheckStructue(app)
                app.TabGroup.SelectedTab = app.DTab_Exp2D;
                app.TabGroup_FigOption.SelectedTab = app.TwoDTab;
                app.TabGroupTools.SelectedTab = app.Tab_2DSFG;
                % Determine if re-calculate
                if app.Refresh_2DSFG
                    output = iteration(app,@TwoDSFG_Main_Sparse,'twoD');
                    SpectraGrid = output.SpectraGrid;
                    Response    = output.Response;
                    CVL         = output.CVL;
                    app.RefreshSource = '2DSFG';
                    RefreshOff(app)
                else
                    Response    = app.Data_2DSFG.Response;
                    SpectraGrid = app.Data_2DSFG.SpectraGrid;
                    
                    I = Parse_GUI(app);
                    CVL = Conv2D(SpectraGrid,I);
                    CVL.FilesName = app.Structure.FilesName; % pass filesname for figure title
                        
                    if app.ExisitingfigureCheckBox.Value
                        hAx_final = findobj(evalin('base',app.hFEditField.Value),'Type','Axes');
                    else
                        hF_final  = figure;
                        hAx_final = axes('Parent',hF_final);
                    end
                    Plot2D(hAx_final,CVL,I,Response.SpecType);
                end
                
                % Output
                app.Data_2DSFG.SpectraGrid = SpectraGrid;
                app.Data_2DSFG.Response    = Response;
                app.Data_2DSFG.CVL         = CVL;
                app.Data_2DSFG.SpecType    = '2DSFG';
            end
        end

        % Button pushed function: Button_Orient_2DSFG
        function Button_Orient_2DSFGPushed(app, event)
            app.hOrient = OrientationAnalysis('COSMOSS',app,'2DSFG');
        end

        % Button pushed function: Button_TS_FTIR
        function Button_TS_FTIRPushed(app, event)
            Transition_Strength(app.Data_FTIR)
        end

        % Button pushed function: Button_TS_SFG
        function Button_TS_SFGPushed(app, event)
            Transition_Strength(app.Data_SFG)
        end

        % Button pushed function: Button_TS_2DIR
        function Button_TS_2DIRPushed(app, event)
            Transition_Strength(app.Data_2DIR)
        end

        % Button pushed function: Button_TS_2DSFG
        function Button_TS_2DSFGPushed(app, event)
            Transition_Strength(app.Data_2DSFG)
        end

        % Button pushed function: Button_Orient_1DSFG
        function Button_Orient_1DSFGPushed(app, event)
            app.hOrient = feval(@OrientationAnalysis,'COSMOSS',app,'SFG');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            UUID          = app.UUID;
            resourcePaths = app.resourcePaths;
            delete(app)
            
            % find childrens
            hUIFigures = findall(0, 'HandleVisibility', 'off');
            for j = 1:length(hUIFigures)
                if isequal(hUIFigures(j).Tag,UUID)
                    delete(hUIFigures(j))
                end
            end
            
            % remove the path only if the closing COSMOSS is the last one
            hCOSMOSS = findall(hUIFigures,'Name','COSMOSS');
            if isequal(length(hCOSMOSS),0)
                for i = 1:length(resourcePaths)
                    rmpath(resourcePaths{i})
                end
            end
            
            
        end

        % Button pushed function: Button_Cut_2DIR, Button_Cut_2DSFG
        function Button_2DCut_Pushed(app, event)
            Cuts
        end

        % Button pushed function: CurrentStructureButton
        function CurrentStructureButtonPushed(app, event)
            app.Structure.SD_Draw;
        end

        % Button pushed function: ResponseVisulizationButton_SFG
        function Button_ResponseVisulization_SFG(app, event)
            app.hResVis = feval(@ResVis,'COSMOSS',app,'SFG');
        end

        % Button pushed function: ResponseVisulizationButton_FTIR
        function Button_ResponseVisulization_FTIR(app, event)
            app.hResVis = feval(@ResVis,'COSMOSS',app,'FTIR');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'COSMOSS';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_Structure = uipanel(app.UIFigure);
            app.Panel_Structure.Title = 'Build Model Structure';
            app.ListBox_Model = uilistbox(app.Panel_Structure);
            app.ListBox_Model.Items = {'Two Couple Oscillators Model', 'Amide-I modes from PDB structure', '2D Grid from formatted ab initio output', 'Amide-I of ideal beta-sheet', 'Combination of any two model above'};
            app.ListBox_Model.ItemsData = {'1', '2', '3', '4', '5'};
            app.ListBox_Model.Multiselect = 'on';
            app.ListBox_Model.Value = {'1'};
            app.Button_SelectModel = uibutton(app.Panel_Structure, 'push');
            app.Button_SelectModel.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectModelPushed, true);
            app.Button_SelectModel.Text = 'Constructing structural model';
            app.PathwayCutoffLabel = uilabel(app.Panel_Structure);
            app.PathwayCutoffLabel.Text = 'Pathway Cutoff (%)';
            app.EditField_FeynmannCutoff = uieditfield(app.Panel_Structure, 'numeric');
            app.Panel_ExpSetup = uipanel(app.UIFigure);
            app.Panel_ExpSetup.Title = 'Experimental Setting';
            app.TabGroup = uitabgroup(app.Panel_ExpSetup);
            app.DTab_Exp1D = uitab(app.TabGroup);
            app.DTab_Exp1D.Title = '1D';
            app.UITable_1D = uitable(app.DTab_Exp1D);
            app.UITable_1D.ColumnName = {'IR'; 'Visible'; 'Signal'};
            app.UITable_1D.RowName = {'Ang.'; 'Pol.'};
            app.UITable_1D.ColumnEditable = true;
            app.DTab_Exp2D = uitab(app.TabGroup);
            app.DTab_Exp2D.Title = '2D';
            app.UITable_2D = uitable(app.DTab_Exp2D);
            app.UITable_2D.ColumnName = {'Pump1'; 'Pump2'; 'Probe'; 'Visible'; 'Signal'};
            app.UITable_2D.RowName = {'Ang.'; 'Pol.'};
            app.UITable_2D.ColumnEditable = true;
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure Setting';
            app.CheckBox_SaveFig = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_SaveFig.Text = 'Save Fig to: ';
            app.EditField_SavePath = uieditfield(app.Panel_FigOpt, 'text');
            app.EditField_SavePath.Value = '~/Desktop/TMP';
            app.ExisitingfigureCheckBox = uicheckbox(app.Panel_FigOpt);
            app.ExisitingfigureCheckBox.Text = 'Draw on exisiting figure ->';
            app.fighandleLabel = uilabel(app.Panel_FigOpt);
            app.fighandleLabel.Text = 'fig. handle:';
            app.hFEditField = uieditfield(app.Panel_FigOpt, 'text');
            app.TabGroup_FigOption = uitabgroup(app.Panel_FigOpt);
            app.OneDTab = uitab(app.TabGroup_FigOption);
            app.OneDTab.Title = '1D';
            app.cm1Label1_1D = uilabel(app.OneDTab);
            app.cm1Label1_1D.Text = 'cm-1';
            app.cm1Label2_1D = uilabel(app.OneDTab);
            app.cm1Label2_1D.Text = 'cm-1';
            app.FrequencyrangeMinLabel_2 = uilabel(app.OneDTab);
            app.FrequencyrangeMinLabel_2.Text = 'Frequency range Min:';
            app.EditField_FreqMin_1D = uieditfield(app.OneDTab, 'numeric');
            app.EditField_FreqMin_1D.Value = 1600;
            app.MaxLabel_2 = uilabel(app.OneDTab);
            app.MaxLabel_2.Text = 'Max:';
            app.EditField_FreqMax_1D = uieditfield(app.OneDTab, 'numeric');
            app.EditField_FreqMax_1D.Value = 1750;
            app.LineShapeLabel_2 = uilabel(app.OneDTab);
            app.LineShapeLabel_2.Text = 'Line Shape:';
            app.DropDown_LineShape_1D = uidropdown(app.OneDTab);
            app.DropDown_LineShape_1D.Items = {'Lorentzian', 'Gaussian', 'No Conv', 'Spy'};
            app.DropDown_LineShape_1D.Value = 'Lorentzian';
            app.WidthLabel_2 = uilabel(app.OneDTab);
            app.WidthLabel_2.Text = 'Width:';
            app.EditField_Width_1D = uieditfield(app.OneDTab, 'numeric');
            app.EditField_Width_1D.Value = 10;
            app.CheckBox_Sticks_1D = uicheckbox(app.OneDTab);
            app.CheckBox_Sticks_1D.Text = 'Sticks';
            app.CheckBox_Sticks_1D.Value = true;
            app.CheckBox_Normalize_1D = uicheckbox(app.OneDTab);
            app.CheckBox_Normalize_1D.Text = 'Normalize';
            app.CheckBox_Normalize_1D.Value = true;
            app.CheckBox_Cursor_1D = uicheckbox(app.OneDTab);
            app.CheckBox_Cursor_1D.Text = 'Cursor';
            app.SignalTypeLabel_2 = uilabel(app.OneDTab);
            app.SignalTypeLabel_2.Text = 'Signal Type:';
            app.DropDown_SignalType_1D = uidropdown(app.OneDTab);
            app.DropDown_SignalType_1D.Items = {'Heterodyne', 'Homodyne'};
            app.DropDown_SignalType_1D.Value = 'Heterodyne';
            app.CheckBox_IntArea_1D = uicheckbox(app.OneDTab);
            app.CheckBox_IntArea_1D.Text = 'Integral Area';
            app.CheckBox_YSym_1D = uicheckbox(app.OneDTab);
            app.CheckBox_YSym_1D.Text = 'Y axis symmetrized';
            app.TwoDTab = uitab(app.TabGroup_FigOption);
            app.TwoDTab.Title = '2D';
            app.ColormapLabel = uilabel(app.TwoDTab);
            app.ColormapLabel.Text = 'Colormap:';
            app.DropDown_ColorMap_2D = uidropdown(app.TwoDTab);
            app.SignalTypeLabel = uilabel(app.TwoDTab);
            app.SignalTypeLabel.Enable = 'off';
            app.SignalTypeLabel.Text = 'Signal Type:';
            app.DropDown_SignalType_2D = uidropdown(app.TwoDTab);
            app.DropDown_SignalType_2D.Items = {'Heterodyne', 'Homodyne'};
            app.DropDown_SignalType_2D.Enable = 'off';
            app.DropDown_SignalType_2D.Value = 'Heterodyne';
            app.SpectralTypeLabel = uilabel(app.TwoDTab);
            app.SpectralTypeLabel.Text = 'Spectral Type:';
            app.DropDown_SpectralType_2D = uidropdown(app.TwoDTab);
            app.DropDown_SpectralType_2D.Items = {'Absorptive', 'Rephasing', 'Non-rephasing'};
            app.DropDown_SpectralType_2D.Value = 'Absorptive';
            app.PathwayLabel = uilabel(app.TwoDTab);
            app.PathwayLabel.Text = 'Pathway:';
            app.DropDown_Pathway_2D = uidropdown(app.TwoDTab);
            app.DropDown_Pathway_2D.Items = {'All', 'GB', 'SE', 'EA'};
            app.DropDown_Pathway_2D.Value = 'All';
            app.cm1Label1_2D = uilabel(app.TwoDTab);
            app.cm1Label1_2D.Text = 'cm-1';
            app.cm1Label2_2D = uilabel(app.TwoDTab);
            app.cm1Label2_2D.Text = 'cm-1';
            app.FrequencyrangeMinLabel = uilabel(app.TwoDTab);
            app.FrequencyrangeMinLabel.Text = 'Frequency range Min:';
            app.EditField_FreqMin_2D = uieditfield(app.TwoDTab, 'numeric');
            app.EditField_FreqMin_2D.Value = 1600;
            app.MaxLabel = uilabel(app.TwoDTab);
            app.MaxLabel.Text = 'Max:';
            app.EditField_FreqMax_2D = uieditfield(app.TwoDTab, 'numeric');
            app.EditField_FreqMax_2D.Value = 1750;
            app.LineShapeLabel = uilabel(app.TwoDTab);
            app.LineShapeLabel.Text = 'Line Shape:';
            app.DropDown_LineShape_2D = uidropdown(app.TwoDTab);
            app.DropDown_LineShape_2D.Items = {'Lorentzian', 'Gaussian', 'No Conv', 'Spy'};
            app.DropDown_LineShape_2D.Value = 'Lorentzian';
            app.WidthLabel = uilabel(app.TwoDTab);
            app.WidthLabel.Text = 'Width:';
            app.EditField_Width_2D = uieditfield(app.TwoDTab, 'numeric');
            app.EditField_Width_2D.Value = 10;
            app.CheckBox_Normalize_2D = uicheckbox(app.TwoDTab);
            app.CheckBox_Normalize_2D.Text = 'Normalize';
            app.CheckBox_Normalize_2D.Value = true;
            app.CheckBox_Cursor_2D = uicheckbox(app.TwoDTab);
            app.CheckBox_Cursor_2D.Text = 'Cursor';
            app.ContourEditFieldLabel = uilabel(app.TwoDTab);
            app.ContourEditFieldLabel.Text = 'Contour#:';
            app.EditField_Contour_2D = uieditfield(app.TwoDTab, 'numeric');
            app.EditField_Contour_2D.Value = 40;
            app.Panel_AnalysisTools = uipanel(app.UIFigure);
            app.Panel_AnalysisTools.Title = 'Analysis Tools';
            app.TabGroupTools = uitabgroup(app.Panel_AnalysisTools);
            app.Tab_FTIR = uitab(app.TabGroupTools);
            app.Tab_FTIR.Title = 'FTIR';
            app.Button_TS_FTIR = uibutton(app.Tab_FTIR, 'push');
            app.Button_TS_FTIR.ButtonPushedFcn = createCallbackFcn(app, @Button_TS_FTIRPushed, true);
            app.Button_TS_FTIR.Text = 'Transition Strength';
            app.ResponseVisulizationButton_FTIR = uibutton(app.Tab_FTIR, 'push');
            app.ResponseVisulizationButton_FTIR.ButtonPushedFcn = createCallbackFcn(app, @Button_ResponseVisulization_FTIR, true);
            app.ResponseVisulizationButton_FTIR.Text = 'Response Visulization';
            app.Tab_SFG = uitab(app.TabGroupTools);
            app.Tab_SFG.Title = 'SFG';
            app.Button_TS_SFG = uibutton(app.Tab_SFG, 'push');
            app.Button_TS_SFG.ButtonPushedFcn = createCallbackFcn(app, @Button_TS_SFGPushed, true);
            app.Button_TS_SFG.Text = 'Transition Strength';
            app.Button_Orient_1DSFG = uibutton(app.Tab_SFG, 'push');
            app.Button_Orient_1DSFG.ButtonPushedFcn = createCallbackFcn(app, @Button_Orient_1DSFGPushed, true);
            app.Button_Orient_1DSFG.Text = 'Orientation Analysis';
            app.ResponseVisulizationButton_SFG = uibutton(app.Tab_SFG, 'push');
            app.ResponseVisulizationButton_SFG.ButtonPushedFcn = createCallbackFcn(app, @Button_ResponseVisulization_SFG, true);
            app.ResponseVisulizationButton_SFG.Text = 'Response Visulization';
            app.Tab_2DIR = uitab(app.TabGroupTools);
            app.Tab_2DIR.Title = '2D IR';
            app.Button_TS_2DIR = uibutton(app.Tab_2DIR, 'push');
            app.Button_TS_2DIR.ButtonPushedFcn = createCallbackFcn(app, @Button_TS_2DIRPushed, true);
            app.Button_TS_2DIR.Text = 'Transition Strength';
            app.Button_Cut_2DIR = uibutton(app.Tab_2DIR, 'push');
            app.Button_Cut_2DIR.ButtonPushedFcn = createCallbackFcn(app, @Button_2DCut_Pushed, true);
            app.Button_Cut_2DIR.Text = '2D Cut';
            app.Tab_2DSFG = uitab(app.TabGroupTools);
            app.Tab_2DSFG.Title = '2D SFG';
            app.Button_Orient_2DSFG = uibutton(app.Tab_2DSFG, 'push');
            app.Button_Orient_2DSFG.ButtonPushedFcn = createCallbackFcn(app, @Button_Orient_2DSFGPushed, true);
            app.Button_Orient_2DSFG.Text = 'Orientation Analysis';
            app.Button_TS_2DSFG = uibutton(app.Tab_2DSFG, 'push');
            app.Button_TS_2DSFG.ButtonPushedFcn = createCallbackFcn(app, @Button_TS_2DSFGPushed, true);
            app.Button_TS_2DSFG.Text = 'Transition Strength';
            app.Button_Cut_2DSFG = uibutton(app.Tab_2DSFG, 'push');
            app.Button_Cut_2DSFG.ButtonPushedFcn = createCallbackFcn(app, @Button_2DCut_Pushed, true);
            app.Button_Cut_2DSFG.Text = '2D Cut';
            app.Button_FTIR = uibutton(app.UIFigure, 'push');
            app.Button_FTIR.ButtonPushedFcn = createCallbackFcn(app, @Button_FTIRPushed, true);
            app.Button_FTIR.Text = 'FTIR';
            app.Button_SFG = uibutton(app.UIFigure, 'push');
            app.Button_SFG.ButtonPushedFcn = createCallbackFcn(app, @Button_SFGPushed, true);
            app.Button_SFG.Text = 'SFG';
            app.Button_2DIR = uibutton(app.UIFigure, 'push');
            app.Button_2DIR.ButtonPushedFcn = createCallbackFcn(app, @Button_2DIRPushed, true);
            app.Button_2DIR.Text = '2D IR';
            app.Button_2DSFG = uibutton(app.UIFigure, 'push');
            app.Button_2DSFG.ButtonPushedFcn = createCallbackFcn(app, @Button_2DSFGPushed, true);
            app.Button_2DSFG.Text = '2D SFG';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Panel_SampleSymmetry = uipanel(app.UIFigure);
            app.Panel_SampleSymmetry.Title = 'Sample Symmetry';
            app.RotationalAverageDropDownLabel = uilabel(app.Panel_SampleSymmetry);
            app.RotationalAverageDropDownLabel.Text = 'Rotational Average';
            app.DropDown_RotAvg = uidropdown(app.Panel_SampleSymmetry);
            app.DropDown_RotAvg.Items = {'Phi', 'Psi', 'Phi,Psi', 'Isotropic', 'C1'};
            app.DropDown_RotAvg.Value = 'Phi';
            app.MirrorPlaneDropDownLabel = uilabel(app.Panel_SampleSymmetry);
            app.MirrorPlaneDropDownLabel.Text = 'Mirror Plane';
            app.DropDown_MirrorPlane = uidropdown(app.Panel_SampleSymmetry);
            app.DropDown_MirrorPlane.Items = {'No', 'Signam V'};
            app.DropDown_MirrorPlane.Value = 'No';
            app.Panel_EnsembleAverage = uipanel(app.UIFigure);
            app.Panel_EnsembleAverage.Title = 'Ensemble Average';
            app.CheckBox_Sampling = uicheckbox(app.Panel_EnsembleAverage);
            app.CheckBox_Sampling.Text = 'Random';
            app.SamplingLabel = uilabel(app.Panel_EnsembleAverage);
            app.SamplingLabel.Text = 'Sampling #';
            app.EditField_SampleN = uieditfield(app.Panel_EnsembleAverage, 'numeric');
            app.EditField_SampleN.Value = 1;
            app.CheckBox_DynamicFigUpdate = uicheckbox(app.Panel_EnsembleAverage);
            app.CheckBox_DynamicFigUpdate.Text = 'Dynamic Fig Update';
            app.CheckBox_Continue = uicheckbox(app.Panel_EnsembleAverage);
            app.CheckBox_Continue.Text = 'Continue?';
            app.CheckBox_Continue.Value = true;
            app.CheckBox_ViewSampling = uicheckbox(app.Panel_EnsembleAverage);
            app.CheckBox_ViewSampling.Text = 'Visulize sampling';
            app.FluctuationCorrelationLabel = uilabel(app.Panel_EnsembleAverage);
            app.FluctuationCorrelationLabel.Text = 'Fluctuation Correlation (%)';
            app.EditField_FluctuationCorrelation = uieditfield(app.Panel_EnsembleAverage, 'numeric');
            app.DiagonalDisordercm1Label = uilabel(app.Panel_EnsembleAverage);
            app.DiagonalDisordercm1Label.Text = 'Diagonal Disorder (cm-1)';
            app.EditField_DD = uieditfield(app.Panel_EnsembleAverage, 'numeric');
            app.OffdiagonalDisordercm1Label = uilabel(app.Panel_EnsembleAverage);
            app.OffdiagonalDisordercm1Label.Text = 'Off-diagonal Disorder (cm-1)';
            app.EditField_ODD = uieditfield(app.Panel_EnsembleAverage, 'numeric');
            app.isotopeDilutionCheckBox = uicheckbox(app.Panel_EnsembleAverage);
            app.isotopeDilutionCheckBox.Enable = 'off';
            app.isotopeDilutionCheckBox.Text = 'permute strand for isotope dilution';
            app.DropDown_ViewSamplingMode = uidropdown(app.Panel_EnsembleAverage);
            app.DropDown_ViewSamplingMode.Items = {'Frequency', 'Mode Index'};
            app.DropDown_ViewSamplingMode.Enable = 'off';
            app.DropDown_ViewSamplingMode.Value = 'Frequency';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V 2.4.0';
            app.CurrentStructureButton = uibutton(app.UIFigure, 'push');
            app.CurrentStructureButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentStructureButtonPushed, true);
            app.CurrentStructureButton.Text = 'Current Structure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COSMOSS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end