classdef Model_PDB_AmideOne < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel_Orientation       matlab.ui.container.Panel
        Button_LoadPDB          matlab.ui.control.Button
        CheckBox_MDsnapshots    matlab.ui.control.CheckBox
        MoleculeEditFieldLabel  matlab.ui.control.Label
        EditField_PDB_Name      matlab.ui.control.EditField
        PhiEditFieldLabel       matlab.ui.control.Label
        EditField_Phi           matlab.ui.control.NumericEditField
        PsiEditFieldLabel       matlab.ui.control.Label
        EditField_Psi           matlab.ui.control.NumericEditField
        ThetaEditFieldLabel     matlab.ui.control.Label
        EditField_Theta         matlab.ui.control.NumericEditField
        DegLabel                matlab.ui.control.Label
        Panel_FigOpt            matlab.ui.container.Panel
        CheckBox_Atom           matlab.ui.control.CheckBox
        CheckBox_Bond           matlab.ui.control.CheckBox
        CheckBox_Axis           matlab.ui.control.CheckBox
        CheckBox_Label          matlab.ui.control.CheckBox
        CheckBox_SideChain      matlab.ui.control.CheckBox
        Button_Generate         matlab.ui.control.Button
        Button_Draw             matlab.ui.control.Button
        Button_ExportData       matlab.ui.control.Button
        Version                 matlab.ui.control.Label
        Panel_Freq              matlab.ui.container.Panel
        LabeledFreqcm1          matlab.ui.control.Label
        LabelFreq               matlab.ui.control.NumericEditField
        LabelIndex_2            matlab.ui.control.Label
        LabelInd                matlab.ui.control.EditField
        ModeFreqcm1Label        matlab.ui.control.Label
        LocFreq                 matlab.ui.control.NumericEditField
        FreqModificationLabel   matlab.ui.control.Label
        DropDown_LocFreq        matlab.ui.control.DropDown
        Anharmonicitycm1Label   matlab.ui.control.Label
        Anharm                  matlab.ui.control.NumericEditField
        Panel_Coupling          matlab.ui.container.Panel
        ModelLabel              matlab.ui.control.Label
        DropDown_Coupling       matlab.ui.control.DropDown
        BetaNNcm1Label          matlab.ui.control.Label
        EditField_NN            matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        Parent    % handles of parent
        Structure % molecule structre class
        CombOrder % The sequence number order from comb2
        GUI_Tag   % Tag for GUI recognition
        PDB       % Organized PDB info
    end

    methods (Access = public)
    
        function O = Parse_GUI(app)
            O.Preprocessed = app.CheckBox_MDsnapshots.Value;
            % Orientation -------------------------------------------------------------
            O.Phi_D   =  app.EditField_Phi.Value;
            O.Psi_D   =  app.EditField_Psi.Value;
            O.Theta_D =  app.EditField_Theta.Value;
            
            % For Local Mode frequencies ----------------------------------------------
            O.LocFreqType = app.DropDown_LocFreq.Value;
            O.NLFreq      = app.LocFreq.Value;
            O.Anharm      = app.Anharm.Value;
            O.LFreq       = app.LabelFreq.Value;
            O.L_Index     = str2num(app.LabelInd.Value);
            
            % Coupling model -----------------------------------------------------------
            O.CouplingType = app.DropDown_Coupling.Value;
            O.Beta_NN      = app.EditField_NN.Value;
            
            % For molecule ploting ----------------------------------------------------
            O.Plot_Atoms      = app.CheckBox_Atom.Value;
            O.Plot_Bonds      = app.CheckBox_Bond.Value;
            O.Plot_Axis       = app.CheckBox_Axis.Value;
            O.Plot_Label      = app.CheckBox_Label.Value;
            O.Plot_SideChain  = app.CheckBox_SideChain.Value;
        end
        
        function RefreshSD(app)
            Button_GeneratePushed(app,'')
        end
        
    end

    methods (Access = private)
    
        function Init(app)
            % Setup Coupling model items
            [~,CouplingList] = Coupling('','List','');
            app.DropDown_Coupling.Items = CouplingList;
            
            %% Add Properties Change Listner
            SA = [...
                  app.EditField_Phi,...
                  app.EditField_Psi,...
                  app.EditField_Theta,...
                  app.LabelFreq,...
                  app.LabelInd,...
                  app.LocFreq,...
                  app.DropDown_LocFreq,...
                  app.Anharm,...
                  app.DropDown_Coupling,...
                  app.EditField_NN,...
                  app.CheckBox_Atom,...
                  app.CheckBox_Bond,...
                  app.CheckBox_Axis,...
                  app.CheckBox_Label,...
                  app.CheckBox_SideChain,...
                  ];
            
            for i = 1:length(SA)
                SA(i).ValueChangedFcn = @(~,~)RefreshSD(app);
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.GUI_Tag = 'PDB_AmideI';
            % if called from other GUI, assign parent handle
            if nargin > 1
                ParentInfo = varargin;
            else
                ParentInfo = {'StandAlong'};
            end
            AssignParent(app,ParentInfo)
            
            % initialize the orientation table
            Init(app)
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            ExportGUIData(app)
        end

        % Button pushed function: Button_LoadPDB
        function Button_LoadPDBPushed(app, event)
            GUI_Inputs = Parse_GUI(app);
            app.PDB = Load_PDB(app,GUI_Inputs);
            app.EditField_PDB_Name.Value = app.PDB.FilesName;
        end

        % Button pushed function: Button_Generate
        function Button_GeneratePushed(app, event)
            if isempty(app.PDB)
                Button_LoadPDBPushed(app, event)
            end
            
            % Add AmideI mode info into StructureData class PDB
            S_PDB = SD_GetAmideI(app.PDB);
            
            %% Update the frequency information
            GUI_Inputs = Parse_GUI(app);
            S_PDB.LocAnharm       = ones(S_PDB.Nmodes,1).*GUI_Inputs.Anharm;
            S_PDB.LocFreq         = ones(S_PDB.Nmodes,1).*GUI_Inputs.NLFreq;
            S_PDB.LocFreq(GUI_Inputs.L_Index) = GUI_Inputs.LFreq;       
            
            %% Rotate the molecule
            Phi   = GUI_Inputs.Phi_D/180*pi;
            Psi   = GUI_Inputs.Psi_D/180*pi;
            Theta = GUI_Inputs.Theta_D/180*pi;
            R     = R1_ZYZ_0(Phi,Psi,Theta);
            S_PDB = SD_Rot(S_PDB,R);
            
            % Add figure drawing function and GUI inputs
            S_PDB.hPlotFunc  = @PlotXYZfiles_AmideI;
            S_PDB.GUI_Inputs = app.Parse_GUI;

            S_PDB = SD_1ExH(S_PDB); % Calculate One Exciton Hamiltoian

            % update StructureData to GUIs
            app.Structure = S_PDB;
            Export2Parent(app)

        end

        % Button pushed function: Button_Draw
        function Button_DrawPushed(app, event)
            if isempty(app.Structure)
                Button_GeneratePushed(app, event)
            end
            app.Structure.SD_Draw;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Model PDB Amide-I';
            app.Panel_Orientation = uipanel(app.UIFigure);
            app.Panel_Orientation.Title = 'Molecule & Orientation';
            app.Button_LoadPDB = uibutton(app.Panel_Orientation, 'push');
            app.Button_LoadPDB.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadPDBPushed, true);
            app.Button_LoadPDB.Text = 'Load PDB';
            app.CheckBox_MDsnapshots = uicheckbox(app.Panel_Orientation);
            app.CheckBox_MDsnapshots.Text = 'MD snapshots';
            app.MoleculeEditFieldLabel = uilabel(app.Panel_Orientation);
            app.MoleculeEditFieldLabel.Text = 'Molecule:';
            app.EditField_PDB_Name = uieditfield(app.Panel_Orientation, 'text');
            app.EditField_PDB_Name.Editable = 'off';
            app.EditField_PDB_Name.Value = 'No PDB is loaded...';
            app.PhiEditFieldLabel = uilabel(app.Panel_Orientation);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.EditField_Phi = uieditfield(app.Panel_Orientation, 'numeric');
            app.PsiEditFieldLabel = uilabel(app.Panel_Orientation);
            app.PsiEditFieldLabel.Text = 'Psi';
            app.EditField_Psi = uieditfield(app.Panel_Orientation, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.Panel_Orientation);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.EditField_Theta = uieditfield(app.Panel_Orientation, 'numeric');
            app.DegLabel = uilabel(app.Panel_Orientation);
            app.DegLabel.Text = 'Deg.';
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure options';
            app.CheckBox_Atom = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Atom.Text = 'Atom';
            app.CheckBox_Atom.Value = true;
            app.CheckBox_Bond = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Bond.Text = 'Bond';
            app.CheckBox_Bond.Value = true;
            app.CheckBox_Axis = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Axis.Text = 'Axis';
            app.CheckBox_Axis.Value = true;
            app.CheckBox_Label = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Label.Text = 'Label';
            app.CheckBox_Label.Value = true;
            app.CheckBox_SideChain = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_SideChain.Text = 'Side Chain';
            app.CheckBox_SideChain.Value = true;
            app.Button_Generate = uibutton(app.UIFigure, 'push');
            app.Button_Generate.ButtonPushedFcn = createCallbackFcn(app, @Button_GeneratePushed, true);
            app.Button_Generate.Text = 'Generate';
            app.Button_Draw = uibutton(app.UIFigure, 'push');
            app.Button_Draw.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawPushed, true);
            app.Button_Draw.Text = 'Draw';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V1.3';
            app.Panel_Freq = uipanel(app.UIFigure);
            app.Panel_Freq.Title = 'Local mode setting';
            app.LabeledFreqcm1 = uilabel(app.Panel_Freq);
            app.LabeledFreqcm1.Text = 'Labeled Freq. (cm-1)';
            app.LabelFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LabelFreq.Value = 1630;
            app.LabelIndex_2 = uilabel(app.Panel_Freq);
            app.LabelIndex_2.Text = 'Label Index:';
            app.LabelInd = uieditfield(app.Panel_Freq, 'text');
            app.ModeFreqcm1Label = uilabel(app.Panel_Freq);
            app.ModeFreqcm1Label.Text = 'Mode Freq. (cm-1)';
            app.LocFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LocFreq.Value = 1650;
            app.FreqModificationLabel = uilabel(app.Panel_Freq);
            app.FreqModificationLabel.Text = 'Freq. Modification';
            app.DropDown_LocFreq = uidropdown(app.Panel_Freq);
            app.DropDown_LocFreq.Items = {'None', 'Jensen Map'};
            app.DropDown_LocFreq.Value = 'None';
            app.Anharmonicitycm1Label = uilabel(app.Panel_Freq);
            app.Anharmonicitycm1Label.Text = 'Anharmonicity (cm-1)';
            app.Anharm = uieditfield(app.Panel_Freq, 'numeric');
            app.Anharm.Value = 10;
            app.Panel_Coupling = uipanel(app.UIFigure);
            app.Panel_Coupling.Title = 'Coupling Model';
            app.ModelLabel = uilabel(app.Panel_Coupling);
            app.ModelLabel.Text = 'Model';
            app.DropDown_Coupling = uidropdown(app.Panel_Coupling);
            app.DropDown_Coupling.Items = {'TDC', 'NN + TDC', 'Jensen Map + TDC', 'Zero'};
            app.DropDown_Coupling.Value = 'TDC';
            app.BetaNNcm1Label = uilabel(app.Panel_Coupling);
            app.BetaNNcm1Label.Text = 'Beta NN (cm-1)';
            app.EditField_NN = uieditfield(app.Panel_Coupling, 'numeric');
            app.EditField_NN.Value = 0.8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_PDB_AmideOne(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end