classdef Model_Betasheet_AmideI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_BSheet                  matlab.ui.container.Panel
        TypeLabel                     matlab.ui.control.Label
        SheetType                     matlab.ui.control.DropDown
        residueRLabel                 matlab.ui.control.Label
        N_Residue                     matlab.ui.control.NumericEditField
        strandsSLabel                 matlab.ui.control.Label
        N_Strand                      matlab.ui.control.NumericEditField
        UITable_Strand                matlab.ui.control.Table
        Panel_FigOpt                  matlab.ui.container.Panel
        CheckBox_Atom                 matlab.ui.control.CheckBox
        CheckBox_Bond                 matlab.ui.control.CheckBox
        CheckBox_Axis                 matlab.ui.control.CheckBox
        CheckBox_Label                matlab.ui.control.CheckBox
        Button_Generate               matlab.ui.control.Button
        Button_Draw                   matlab.ui.control.Button
        Button_ExportData             matlab.ui.control.Button
        Version                       matlab.ui.control.Label
        Panel_Coupling                matlab.ui.container.Panel
        CouplingmodeldependentinputLabel  matlab.ui.control.Label
        EditField_NN                  matlab.ui.control.NumericEditField
        CouplingModelLabel_2          matlab.ui.control.Label
        DropDown_Coupling             matlab.ui.control.DropDown
        LocalmodessettingPanel        matlab.ui.container.Panel
        FreqMappingLabel              matlab.ui.control.Label
        DropDown_FreqMap              matlab.ui.control.DropDown
        UITable_LocModes              matlab.ui.control.Table
        StrandpermutationCheckBox     matlab.ui.control.CheckBox
        BaselocalmodefreqF0EditFieldLabel  matlab.ui.control.Label
        BaselocalmodefreqF0EditField  matlab.ui.control.NumericEditField
        baseAnharmEditFieldLabel      matlab.ui.control.Label
        baseAnharmEditField           matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        ParentGUI   % GUI handle of the parent GUI
        fileChooser % a place to keep file chooser handle
        Structure   % molecule structre class % Description
        CombOrder   % The sequence number order from comb2
        GUI_Tag     % Tag for GUI recognition
    end

    methods (Access = private)
        
        function Init(app)
            app.GUI_Tag = 'BSheet';
            
            % Strand parameter table
            Strand_Table = {'Strand translation (A)',0,0,4.75;...
                            'Strand twist (Deg.)',0,0,0;...
                            'Overall rotation (Deg.)',0,0,0};
            Strand_ColumnFormat   = {'char','numeric','numeric','numeric'};
            
            app.UITable_Strand.Data           = Strand_Table;
            app.UITable_Strand.ColumnFormat   = Strand_ColumnFormat;
            
            %% Local mode table
            app.UITable_LocModes.Data = zeros(app.N_Residue.Value * app.N_Strand.Value,7);
            app.UITable_LocModes.Data = refreshLocalModeTable(app);

            % Setup Coupling model items
            [~,CouplingList] = Coupling(app.GUI_Tag,'List','');
            app.DropDown_Coupling.Items = CouplingList;
            app.DropDown_Coupling.Value = CouplingList{5};
            
            %% Add Properties Change Listner
            SA = [...
                  app.SheetType,...
                  app.N_Residue,...
                  app.N_Strand,...
                  app.CheckBox_Atom,...
                  app.CheckBox_Bond,...
                  app.CheckBox_Axis,...
                  app.CheckBox_Label,...
                  app.baseAnharmEditField,...
                  app.BaselocalmodefreqF0EditField,...
                  app.StrandpermutationCheckBox,...
                  app.DropDown_FreqMap,...
                  ];
            
            for i = 1:length(SA)
                SA(i).ValueChangedFcn = @(~,~)RefreshSD(app);
            end
            
            app.UITable_Strand.CellEditCallback   = @(~,~)RefreshSD(app);
            app.UITable_LocModes.CellEditCallback = @(~,~)RefreshSD(app);
        end
        
        
        function Data = refreshLocalModeTable(app)
            N_Modes      = app.N_Residue.Value * app.N_Strand.Value;
            arrayResidue = 1:app.N_Residue.Value;
            arrayStrand  = 1:app.N_Strand.Value;

            [indS,indR] = meshgrid(arrayStrand,arrayResidue);
            
            % check if the new data size changed
            Data_Old    = app.UITable_LocModes.Data;
            N_Modes_Old = size(Data_Old,1);
            if eq(N_Modes,N_Modes_Old)
                Data = Data_Old;
            else
                Data = zeros(N_Modes,7);
            end
            
            Data(:,1) = 1:N_Modes;
            Data(:,2) = indS(:);
            Data(:,3) = indR(:);
            Data(:,4) = ones(N_Modes,1).*app.BaselocalmodefreqF0EditField.Value;
            Data(:,6) = ones(N_Modes,1).*app.baseAnharmEditField.Value;
            
            app.UITable_LocModes.Data = Data;
            
            % modify the table appearence
            indArray = 1:N_Modes;
            oddStrandRow  =  indArray(logical(mod(indS,2)));
            evenStrandRow = indArray(~logical(mod(indS,2)));
            
            s1 = uistyle('BackgroundColor',[1 1 1]);
            s2 = uistyle('BackgroundColor',[0.3010 0.7450 0.9330]);
            s3 = uistyle('BackgroundColor',[0.9290 0.6940 0.1250]);
            
            addStyle(app.UITable_LocModes,s2,'row',oddStrandRow);
            addStyle(app.UITable_LocModes,s3,'row',evenStrandRow);
            addStyle(app.UITable_LocModes,s1,'column',[5,7]);
            

        end
    end
    
    methods (Access = public)

        function O = Parse_GUI(app)
            % Orientation -------------------------------------------------------------
            O.SheetType = app.SheetType.Value;
            O.N_Residue = app.N_Residue.Value;
            O.N_Strand  = app.N_Strand.Value ;
            
            Table_Strand  = app.UITable_Strand.Data;
            O.Trans_X   = Table_Strand{1,2};
            O.Trans_Y   = Table_Strand{1,3};
            O.Trans_Z   = Table_Strand{1,4};
            O.Twist_X   = Table_Strand{2,2};
            O.Twist_Y   = Table_Strand{2,3};
            O.Twist_Z   = Table_Strand{2,4};
            O.Phi_D     = Table_Strand{3,2};
            O.Psi_D     = Table_Strand{3,3};
            O.Theta_D   = Table_Strand{3,4};
                    
            % For Local Mode frequencies ----------------------------------------------
            O.LocModeData = app.UITable_LocModes.Data;
            O.LocFreqType = app.DropDown_FreqMap.Value;
            O.L_Index     = logical(app.UITable_LocModes.Data(:,5));
            
            % Coupling model -----------------------------------------------------------
            O.CouplingType = app.DropDown_Coupling.Value;
            O.Beta_NN      = app.EditField_NN.Value;
            
            % For molecule ploting ----------------------------
            O.Plot_Atoms     = app.CheckBox_Atom.Value;
            O.Plot_Bonds     = app.CheckBox_Bond.Value;
            O.Plot_Axis      = app.CheckBox_Axis.Value;
            O.Plot_Label     = app.CheckBox_Label.Value;
            % -------------------------------------------------
        end
        
        function RefreshSD(app)
            Data = refreshLocalModeTable(app);
            Button_GeneratePushed(app,'');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                ParentInfo = varargin;
            else
                ParentInfo = {'StandAlong'};
            end
            AssignParent(app,ParentInfo)
            
            % initialize the orientation table
            Init(app)
        end

        % Button pushed function: Button_Generate
        function Button_GeneratePushed(app, event)
            GUI_Inputs = Parse_GUI(app);
            S_BSheet   = ConstuctBetaSheet(GUI_Inputs);
           
            % update StructureData to GUIs
            app.Structure = S_BSheet;
            Export2Parent(app)
        end

        % Button pushed function: Button_Draw
        function Button_DrawPushed(app, event)
            app.Structure.SD_Draw;
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            ExportGUIData(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Model Betasheet';
            app.Panel_BSheet = uipanel(app.UIFigure);
            app.Panel_BSheet.Title = 'Betasheet parameters';
            app.TypeLabel = uilabel(app.Panel_BSheet);
            app.TypeLabel.Text = 'Type';
            app.SheetType = uidropdown(app.Panel_BSheet);
            app.SheetType.Items = {'Parallel', 'Anti-Parallel'};
            app.SheetType.Value = 'Anti-Parallel';
            app.residueRLabel = uilabel(app.Panel_BSheet);
            app.residueRLabel.Text = 'residue (R#)';
            app.N_Residue = uieditfield(app.Panel_BSheet, 'numeric');
            app.N_Residue.Value = 5;
            app.strandsSLabel = uilabel(app.Panel_BSheet);
            app.strandsSLabel.Text = 'strands (S#)';
            app.N_Strand = uieditfield(app.Panel_BSheet, 'numeric');
            app.N_Strand.Value = 3;
            app.UITable_Strand = uitable(app.Panel_BSheet);
            app.UITable_Strand.ColumnName = {'Parameters btw. strands'; 'X / φ'; 'Y / ψ'; 'Z / θ'};
            app.UITable_Strand.RowName = {};
            app.UITable_Strand.ColumnEditable = [false true true true];
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure options';
            app.CheckBox_Atom = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Atom.Text = 'Atom';
            app.CheckBox_Atom.Value = true;
            app.CheckBox_Bond = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Bond.Text = 'Bond';
            app.CheckBox_Bond.Value = true;
            app.CheckBox_Axis = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Axis.Text = 'Axis';
            app.CheckBox_Axis.Value = true;
            app.CheckBox_Label = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Label.Text = 'Label';
            app.CheckBox_Label.Value = true;
            app.Button_Generate = uibutton(app.UIFigure, 'push');
            app.Button_Generate.ButtonPushedFcn = createCallbackFcn(app, @Button_GeneratePushed, true);
            app.Button_Generate.Text = 'Generate';
            app.Button_Draw = uibutton(app.UIFigure, 'push');
            app.Button_Draw.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawPushed, true);
            app.Button_Draw.Text = 'Draw';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V1.4';
            app.Panel_Coupling = uipanel(app.UIFigure);
            app.Panel_Coupling.Title = 'Coupling Model';
            app.CouplingmodeldependentinputLabel = uilabel(app.Panel_Coupling);
            app.CouplingmodeldependentinputLabel.Text = 'Coupling model dependent input';
            app.EditField_NN = uieditfield(app.Panel_Coupling, 'numeric');
            app.EditField_NN.Value = 0.8;
            app.CouplingModelLabel_2 = uilabel(app.Panel_Coupling);
            app.CouplingModelLabel_2.Text = 'Coupling Model: ';
            app.DropDown_Coupling = uidropdown(app.Panel_Coupling);
            app.DropDown_Coupling.Items = {};
            app.DropDown_Coupling.Value = {};
            app.LocalmodessettingPanel = uipanel(app.UIFigure);
            app.LocalmodessettingPanel.Title = 'Local modes setting';
            app.FreqMappingLabel = uilabel(app.LocalmodessettingPanel);
            app.FreqMappingLabel.Text = 'Freq. Mapping';
            app.DropDown_FreqMap = uidropdown(app.LocalmodessettingPanel);
            app.DropDown_FreqMap.Items = {'None', 'Jensen Map'};
            app.DropDown_FreqMap.Value = 'None';
            app.UITable_LocModes = uitable(app.LocalmodessettingPanel);
            app.UITable_LocModes.ColumnName = {'Ind.'; 'S#'; 'R#'; 'F0'; 'ΔF'; 'Anharm'; 'ΔAnharm'};
            app.UITable_LocModes.RowName = {};
            app.UITable_LocModes.ColumnEditable = [false false false false true false true];
            app.StrandpermutationCheckBox = uicheckbox(app.LocalmodessettingPanel);
            app.StrandpermutationCheckBox.Text = 'Strand permutation';
            app.BaselocalmodefreqF0EditFieldLabel = uilabel(app.LocalmodessettingPanel);
            app.BaselocalmodefreqF0EditFieldLabel.Text = 'Base local mode freq. (F0)';
            app.BaselocalmodefreqF0EditField = uieditfield(app.LocalmodessettingPanel, 'numeric');
            app.BaselocalmodefreqF0EditField.Value = 1650;
            app.baseAnharmEditFieldLabel = uilabel(app.LocalmodessettingPanel);
            app.baseAnharmEditFieldLabel.Text = 'base Anharm.';
            app.baseAnharmEditField = uieditfield(app.LocalmodessettingPanel, 'numeric');
            app.baseAnharmEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_Betasheet_AmideI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end