classdef Model_CavityModes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel_FigOpt                matlab.ui.container.Panel
        CheckBox_Mirror             matlab.ui.control.CheckBox
        CheckBox_Axis               matlab.ui.control.CheckBox
        Button_Generate             matlab.ui.control.Button
        Button_Draw                 matlab.ui.control.Button
        Button_ExportData           matlab.ui.control.Button
        Version                     matlab.ui.control.Label
        Panel_Freq                  matlab.ui.container.Panel
        LabeledFreqcm1              matlab.ui.control.Label
        LabelFreq                   matlab.ui.control.NumericEditField
        LabelIndex                  matlab.ui.control.Label
        LabelInd                    matlab.ui.control.EditField
        MinTransFreqcm1Label        matlab.ui.control.Label
        LocFreq                     matlab.ui.control.NumericEditField
        FrequencyModeLabel          matlab.ui.control.Label
        LocFreqMode                 matlab.ui.control.DropDown
        FreeSpectralRangecm1Label   matlab.ui.control.Label
        FSR                         matlab.ui.control.NumericEditField
        NumberoftransmissionsLabel  matlab.ui.control.Label
        NCModes                     matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        ParentGUI   % GUI handle of the parent GUI
        fileChooser % a place to keep file chooser handle
        Structure   % StructureData Class 
        CombOrder   % The sequence number order from comb2
        GUI_Tag     % Tag for GUI recognition 
    end

    methods (Access = private)
    
        function Init(app)
            app.GUI_Tag = 'Cavity';
            
            %% Add Properties Change Listner
            SA = [...
                  app.NCModes,...
                  app.CheckBox_Axis,...
                  app.CheckBox_Mirror,...
                  app.LocFreq,...
                  app.LabelFreq,...
                  app.LabelInd,...
                  app.FSR,...
                  app.LocFreqMode,...
                  ];
            
            for i = 1:length(SA)
                SA(i).ValueChangedFcn = @(~,~)RefreshSD(app);
            end
        end

    end

    methods (Access = public)

        function O = Parse_GUI(app)            
            % decide cavity properties ----------------------------------------------
            O.NCModes     = app.NCModes.Value;
            O.FSR         = app.FSR.Value;
            O.LocFreqType = app.LocFreqMode.Value;
            O.NLFreq      = app.LocFreq.Value;
            
            O.LFreq       = app.LabelFreq.Value;
            O.L_Index     = str2num(app.LabelInd.Value);
            
            % For molecule ploting ----------------------------------------------------
            O.Plot_Axis  = app.CheckBox_Axis.Value; 
            O.Plot_Axis  = app.CheckBox_Mirror.Value; 
        end
                
        function RefreshSD(app)
            Button_GeneratePushed(app,'')
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                ParentInfo = varargin;
            else
                ParentInfo = {'StandAlong'};
            end
            AssignParent(app,ParentInfo)
            
            % initialize the orientation table
            Init(app)
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            ExportGUIData(app);
        end

        % Button pushed function: Button_Generate
        function Button_GeneratePushed(app, event)
            %% Read GUI variables
            GUI_Inputs = Parse_GUI(app);
            
            %% Construct the two coupled oscillators
            S_C = ConstructCavity(GUI_Inputs);
            
            % update StructureData to GUIs
            app.Structure = S_C;
            Export2Parent(app)
        end

        % Button pushed function: Button_Draw
        function Button_DrawPushed(app, event)
            app.Structure.SD_Draw;
        end

        % Value changed function: NCModes
        function NCModesValueChanged(app, event)
            value = app.NCModes.Value;
            isOdd = (mod(value,2) == 1); 
            disp('in')
            if ~isOdd
                warning('This value only accept odd value, choosing the cloesest odd number for you...')
                app.NCModes.Value = value + 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Model_CavityModes';
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure options';
            app.CheckBox_Mirror = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Mirror.Enable = 'off';
            app.CheckBox_Mirror.Text = 'Mirrors';
            app.CheckBox_Axis = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Axis.Enable = 'off';
            app.CheckBox_Axis.Text = 'Axis';
            app.Button_Generate = uibutton(app.UIFigure, 'push');
            app.Button_Generate.ButtonPushedFcn = createCallbackFcn(app, @Button_GeneratePushed, true);
            app.Button_Generate.Text = 'Generate';
            app.Button_Draw = uibutton(app.UIFigure, 'push');
            app.Button_Draw.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawPushed, true);
            app.Button_Draw.Text = 'Draw';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V 1.1';
            app.Panel_Freq = uipanel(app.UIFigure);
            app.Panel_Freq.Title = 'Local mode setting';
            app.LabeledFreqcm1 = uilabel(app.Panel_Freq);
            app.LabeledFreqcm1.Enable = 'off';
            app.LabeledFreqcm1.Text = 'Labeled Freq. (cm-1)';
            app.LabelFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LabelFreq.Enable = 'off';
            app.LabelFreq.Value = 1630;
            app.LabelIndex = uilabel(app.Panel_Freq);
            app.LabelIndex.Enable = 'off';
            app.LabelIndex.Text = 'Label Index:';
            app.LabelInd = uieditfield(app.Panel_Freq, 'text');
            app.LabelInd.Enable = 'off';
            app.MinTransFreqcm1Label = uilabel(app.Panel_Freq);
            app.MinTransFreqcm1Label.Text = 'Min. Trans. Freq. (cm-1)';
            app.LocFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LocFreq.Value = 1650;
            app.FrequencyModeLabel = uilabel(app.Panel_Freq);
            app.FrequencyModeLabel.Text = 'Frequency Mode: ';
            app.LocFreqMode = uidropdown(app.Panel_Freq);
            app.LocFreqMode.Items = {'Cavity', 'Symbolic', ''};
            app.LocFreqMode.Value = 'Cavity';
            app.FreeSpectralRangecm1Label = uilabel(app.Panel_Freq);
            app.FreeSpectralRangecm1Label.Text = 'Free Spectral Range (cm-1)';
            app.FSR = uieditfield(app.Panel_Freq, 'numeric');
            app.FSR.Value = 200;
            app.NumberoftransmissionsLabel = uilabel(app.Panel_Freq);
            app.NumberoftransmissionsLabel.Text = 'Number of transmissions';
            app.NCModes = uieditfield(app.Panel_Freq, 'numeric');
            app.NCModes.ValueChangedFcn = createCallbackFcn(app, @NCModesValueChanged, true);
            app.NCModes.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_CavityModes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end