classdef Model_Comb2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel_ModelSelection      matlab.ui.container.Panel
        stLabel                   matlab.ui.control.Label
        DropDown_Model1           matlab.ui.control.DropDown
        ndLabel                   matlab.ui.control.Label
        DropDown_Model2           matlab.ui.control.DropDown
        Button_Model1             matlab.ui.control.Button
        Button_Model2             matlab.ui.control.Button
        Panel_RelativeParameters  matlab.ui.container.Panel
        TransXLabel               matlab.ui.control.Label
        Trans_X                   matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        Trans_Y                   matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        Trans_Z                   matlab.ui.control.NumericEditField
        Label_Ang                 matlab.ui.control.Label
        Label_Deg                 matlab.ui.control.Label
        RotPhiEditFieldLabel      matlab.ui.control.Label
        Rot_Phi                   matlab.ui.control.NumericEditField
        PsiEditFieldLabel         matlab.ui.control.Label
        Rot_Psi                   matlab.ui.control.NumericEditField
        ThetaEditFieldLabel       matlab.ui.control.Label
        Rot_Theta                 matlab.ui.control.NumericEditField
        Relativeconcentrationofthe2ndLabel  matlab.ui.control.Label
        Conc_Scaling              matlab.ui.control.NumericEditField
        Button_Combine            matlab.ui.control.Button
        Button_Draw               matlab.ui.control.Button
        Button_ExportData         matlab.ui.control.Button
        Button_SaveMAT            matlab.ui.control.Button
        Version                   matlab.ui.control.Label
        Panel_Coupling            matlab.ui.container.Panel
        ModelLabel                matlab.ui.control.Label
        DropDown_Coupling         matlab.ui.control.DropDown
        BetaNNcm1Label            matlab.ui.control.Label
        EditField_NN              matlab.ui.control.NumericEditField
    end

    properties (Access = public)
        ParentGUI   % GUI handle of the parent GUI
        fileChooser % a place to keep file chooser handle
        Structure   % generated strucutre
        CombOrder   % The sequence number order from comb2
        GUI_Tag     % Tag for GUI recognition 
        hModel1
        hModel2
        UUID
    end

    methods (Access = public)
    
        function O = Parse_GUI(app)
            % For relative orientation ------------------------------------------------
            O.Trans_X      = app.Trans_X.Value     ;
            O.Trans_Y      = app.Trans_Y.Value     ;
            O.Trans_Z      = app.Trans_Z.Value     ;
            O.Rot_Phi      = app.Rot_Phi.Value     ;
            O.Rot_Psi      = app.Rot_Psi.Value     ;
            O.Rot_Theta    = app.Rot_Theta.Value   ;
            O.Conc_Scaling = app.Conc_Scaling.Value;
            
            % Coupling model -----------------------------------------------------------
            O.CouplingType = app.DropDown_Coupling.Value;
            O.Beta_NN      = app.EditField_NN.Value;
            
        end
        
        function RefreshSD(app)
            Button_CombinePushed(app,'')
        end
        
    end

    methods (Access = private)
    
        function Init(app)
            % Setup the Model structure list
            [~,StructureModelList] = StructureModel('List');
            app.DropDown_Model1.Items = StructureModelList;
            app.DropDown_Model1.ItemsData = 1:length(StructureModelList);
            
            app.DropDown_Model2.Items = StructureModelList;
            app.DropDown_Model2.ItemsData = 1:length(StructureModelList);
            
            % Setup Coupling model items
            [~,CouplingList] = Coupling('','List','');
            app.DropDown_Coupling.Items = CouplingList;
            
            %% Add Properties Change Listner
            SA = [...
                  app.Trans_X,...
                  app.Trans_Y,...
                  app.Trans_Z,...
                  app.Rot_Phi,...
                  app.Rot_Psi,...
                  app.Rot_Theta,...
                  app.Conc_Scaling,...
                  ];
            
            for i = 1:length(SA)
                SA(i).ValueChangedFcn = @(~,~)RefreshSD(app);
            end

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %% generate an UUID
            tmp = java.util.UUID.randomUUID;
            app.UUID = string(tmp.toString);
            
            app.GUI_Tag = 'Comb2';
            % if called from other GUI, assign parent handle
            if nargin > 1
                ParentInfo = varargin;
            else
                ParentInfo = {'StandAlong'};
            end
            AssignParent(app,ParentInfo)
            
            % initialize the orientation table
            Init(app)
        end

        % Button pushed function: Button_Model1
        function Button_Model1Pushed(app, event)
            SelectedModel = app.DropDown_Model1.Value;
            [fhModel,ModelList] = StructureModel(SelectedModel);
            
            app.hModel1 = feval(fhModel,'Comb2',app,1);
            disp(['Model Comb2 using ', ModelList{SelectedModel},' as the 1st model'])
        end

        % Button pushed function: Button_Model2
        function Button_Model2Pushed(app, event)
            SelectedModel = app.DropDown_Model2.Value;
            [fhModel,ModelList] = StructureModel(SelectedModel);
            
            app.hModel2 = feval(fhModel,'Comb2',app,2);
            disp(['Model Comb2 using ', ModelList{SelectedModel},' as the 2nd model'])
        end

        % Button pushed function: Button_Combine
        function Button_CombinePushed(app, event)
            StrucData1 = app.hModel1.Structure;
            StrucData2 = app.hModel2.Structure;
            GUI_Inputs = Parse_GUI(app);
            
            if ~and(isa(StrucData1,'StructureData'),isa(StrucData2,'StructureData'))
                disp('Comb2 waiting for all models ready to combine...')
                return
            end
            
            SC = Comb2(StrucData1,StrucData2,GUI_Inputs);
            
            % update StructureData to GUIs
            app.Structure = SC;
            Export2Parent(app)
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            ExportGUIData(app) 
        end

        % Button pushed function: Button_Draw
        function Button_DrawPushed(app, event)
            app.Structure.SD_Draw;
        end

        % Button pushed function: Button_SaveMAT
        function Button_SaveMATPushed(app, event)
            PWD = pwd;
            Default_folder = [PWD, '/StructureFiles/'];
            [FilesName,PathName] = uiputfile('*.mat','Save Model Strcutre As',Default_folder);
            
            SD = app.Structure; % rename the Structure to be SD so the LoadStructureData.m can load it with this name 
            save([PathName,FilesName],'SD')
            disp(['Model Structure saved in ',PathName,FilesName])
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % find childrens
            hUIFigures = findall(0, 'HandleVisibility', 'off');
            for j = 1:length(hUIFigures)
                if isequal(hUIFigures(j).Tag,app.UUID)
                    delete(hUIFigures(j))
                end
            end
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Model Comb2';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_ModelSelection = uipanel(app.UIFigure);
            app.Panel_ModelSelection.Title = 'Model Selection';
            app.stLabel = uilabel(app.Panel_ModelSelection);
            app.stLabel.Text = '1st:';
            app.DropDown_Model1 = uidropdown(app.Panel_ModelSelection);
            app.ndLabel = uilabel(app.Panel_ModelSelection);
            app.ndLabel.Text = '2nd:';
            app.DropDown_Model2 = uidropdown(app.Panel_ModelSelection);
            app.Button_Model1 = uibutton(app.Panel_ModelSelection, 'push');
            app.Button_Model1.ButtonPushedFcn = createCallbackFcn(app, @Button_Model1Pushed, true);
            app.Button_Model1.Text = 'Model 1';
            app.Button_Model2 = uibutton(app.Panel_ModelSelection, 'push');
            app.Button_Model2.ButtonPushedFcn = createCallbackFcn(app, @Button_Model2Pushed, true);
            app.Button_Model2.Text = 'Model 2';
            app.Panel_RelativeParameters = uipanel(app.UIFigure);
            app.Panel_RelativeParameters.Title = 'Orientational parameters of the 2nd model';
            app.TransXLabel = uilabel(app.Panel_RelativeParameters);
            app.TransXLabel.Text = 'Trans.   X';
            app.Trans_X = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.YEditFieldLabel = uilabel(app.Panel_RelativeParameters);
            app.YEditFieldLabel.Text = 'Y';
            app.Trans_Y = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.ZEditFieldLabel = uilabel(app.Panel_RelativeParameters);
            app.ZEditFieldLabel.Text = 'Z';
            app.Trans_Z = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.Label_Ang = uilabel(app.Panel_RelativeParameters);
            app.Label_Ang.Text = 'Ang.';
            app.Label_Deg = uilabel(app.Panel_RelativeParameters);
            app.Label_Deg.Text = 'Deg.';
            app.RotPhiEditFieldLabel = uilabel(app.Panel_RelativeParameters);
            app.RotPhiEditFieldLabel.Text = 'Rot.   Phi';
            app.Rot_Phi = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.PsiEditFieldLabel = uilabel(app.Panel_RelativeParameters);
            app.PsiEditFieldLabel.Text = 'Psi';
            app.Rot_Psi = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.Panel_RelativeParameters);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.Rot_Theta = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.Relativeconcentrationofthe2ndLabel = uilabel(app.Panel_RelativeParameters);
            app.Relativeconcentrationofthe2ndLabel.Text = 'Relative concentration of the 2nd ';
            app.Conc_Scaling = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.Conc_Scaling.Value = 1;
            app.Button_Combine = uibutton(app.UIFigure, 'push');
            app.Button_Combine.ButtonPushedFcn = createCallbackFcn(app, @Button_CombinePushed, true);
            app.Button_Combine.Text = 'Combine';
            app.Button_Draw = uibutton(app.UIFigure, 'push');
            app.Button_Draw.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawPushed, true);
            app.Button_Draw.Text = 'Draw';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Button_SaveMAT = uibutton(app.UIFigure, 'push');
            app.Button_SaveMAT.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveMATPushed, true);
            app.Button_SaveMAT.Text = 'Save Model';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V 1.4';
            app.Panel_Coupling = uipanel(app.UIFigure);
            app.Panel_Coupling.Title = 'Coupling Model';
            app.ModelLabel = uilabel(app.Panel_Coupling);
            app.ModelLabel.Text = 'Model';
            app.DropDown_Coupling = uidropdown(app.Panel_Coupling);
            app.DropDown_Coupling.Items = {};
            app.DropDown_Coupling.Value = {};
            app.BetaNNcm1Label = uilabel(app.Panel_Coupling);
            app.BetaNNcm1Label.Text = 'Beta NN (cm-1)';
            app.EditField_NN = uieditfield(app.Panel_Coupling, 'numeric');
            app.EditField_NN.Value = 0.8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_Comb2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end