classdef Model_NCO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel_Sturcture        matlab.ui.container.Panel
        UITable                matlab.ui.control.Table
        OverallrotPhiLabel     matlab.ui.control.Label
        Phi                    matlab.ui.control.NumericEditField
        PsiLabel               matlab.ui.control.Label
        Psi                    matlab.ui.control.NumericEditField
        ThetaLabel             matlab.ui.control.Label
        Theta                  matlab.ui.control.NumericEditField
        Panel_FigOpt           matlab.ui.container.Panel
        CheckBox_Atom          matlab.ui.control.CheckBox
        CheckBox_Bond          matlab.ui.control.CheckBox
        CheckBox_Axis          matlab.ui.control.CheckBox
        Button_Generate        matlab.ui.control.Button
        Button_Draw            matlab.ui.control.Button
        Button_ExportData      matlab.ui.control.Button
        Version                matlab.ui.control.Label
        Panel_Coupling         matlab.ui.container.Panel
        FreqModeDropDownLabel  matlab.ui.control.Label
        FreqModeDropDown       matlab.ui.control.DropDown
        CouplingInputLabel     matlab.ui.control.Label
        EditField_NN           matlab.ui.control.NumericEditField
        CouplingModelLabel     matlab.ui.control.Label
        DropDown_Coupling      matlab.ui.control.DropDown
    end


    properties (Access = public)
        ParentGUI   % GUI handle of the parent GUI
        fileChooser % a place to keep file chooser handle
        Structure   % StructureData Class 
        CombOrder   % The sequence number order from comb2
        GUI_Tag     % Tag for GUI recognition 
        LocFreqType = 'NCO' % switch between normal and symbolic mode
    end

    methods (Access = private)
    
        function Init(app)
            app.GUI_Tag = 'NCO';
            %% Initial vlues of the Orientation inputs
            Orientation = zeros(10,10);
            Orientation(1:2,1) = true;
            Orientation(2,5) = 3.5;
            Orientation(1,4) = -30;
            Orientation(2,4) = 30;
            Orientation(:,8) = 1;
            Orientation(:,9) = 1650;
            Orientation(:,10) = 10;
            
            ColumnFormat   = {'logical','bank','bank', 'bank','bank','bank','bank','bank'};

            app.UITable.Data           = Orientation;
            app.UITable.ColumnFormat   = ColumnFormat;
            
            % Setup Coupling model items
            [~,CouplingList] = Coupling('','List','');
            app.DropDown_Coupling.Items = CouplingList;
            
            %% Add Properties Change Listner
            SA = [...
                  app.Phi,...
                  app.Psi,...
                  app.Theta,...
                  app.CheckBox_Atom,...
                  app.CheckBox_Bond,...
                  app.CheckBox_Axis,...
                  app.DropDown_Coupling,...
                  app.EditField_NN,...
                  ];
            
            for i = 1:length(SA)
                SA(i).ValueChangedFcn = @(~,~)RefreshSD(app);
            end
            
            app.UITable.CellEditCallback = @(~,~)RefreshSD(app);
        end

    end

    methods (Access = public)

        function O = Parse_GUI(app)
            % Read data from Table
            O.StructureInputs = app.UITable.Data;
            
            % Read orientation GUI inputs
            O.Phi   = app.Phi.Value;
            O.Psi   = app.Psi.Value;
            O.Theta = app.Theta.Value;
            
            O.LocFreqType = app.LocFreqType;
            
            % Coupling model -----------------------------------------------------------
            O.CouplingType = app.DropDown_Coupling.Value;
            O.Beta_NN      = app.EditField_NN.Value;
            
            % For molecule ploting ----------------------------------------------------
            O.Plot_Atoms = app.CheckBox_Atom.Value;
            O.Plot_Bonds = app.CheckBox_Bond.Value;
            O.Plot_Axis  = app.CheckBox_Axis.Value; 
        end
                
        function RefreshSD(app)
            Button_GeneratePushed(app,'')
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                ParentInfo = varargin;
            else
                ParentInfo = {'StandAlong'};
            end
            AssignParent(app,ParentInfo)
            
            % initialize the orientation table
            Init(app)
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            ExportGUIData(app);
        end

        % Button pushed function: Button_Generate
        function Button_GeneratePushed(app, event)
            %% Read GUI variables
            GUI_Inputs = Parse_GUI(app);
            
            %% Construct the two coupled oscillators
            NCO = ConstructNCO(GUI_Inputs);
            
            % update StructureData to GUIs
            app.Structure = NCO;
            Export2Parent(app)
        end

        % Button pushed function: Button_Draw
        function Button_DrawPushed(app, event)
            app.Structure.SD_Draw;
        end

        % Value changed function: FreqModeDropDown
        function FreqModeDropDownValueChanged(app, event)
            value = app.FreqModeDropDown.Value;
            switch value
                case 'Numeric'
                    app.LocFreqType = 'NCO';
                    app.DropDown_Coupling.Value = 'TDC';
                case 'Symbolic'
                    app.LocFreqType = 'Symbolic';
                    app.DropDown_Coupling.Value = 'Symbolic';
            end
        end

        % Callback function
        function EditField_NNValueChanged(app, event)
            
        end

        % Callback function
        function DropDown_CouplingValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Model_NCO';
            app.Panel_Sturcture = uipanel(app.UIFigure);
            app.Panel_Sturcture.Title = 'Orientation';
            app.UITable = uitable(app.Panel_Sturcture);
            app.UITable.ColumnName = {''; 'Phi'; 'Psi'; 'Theta'; 'X'; 'Y'; 'Z'; 'SF'; 'Freq'; 'Anharm'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.OverallrotPhiLabel = uilabel(app.Panel_Sturcture);
            app.OverallrotPhiLabel.Text = 'Overall rot.: Phi';
            app.Phi = uieditfield(app.Panel_Sturcture, 'numeric');
            app.PsiLabel = uilabel(app.Panel_Sturcture);
            app.PsiLabel.Text = 'Psi';
            app.Psi = uieditfield(app.Panel_Sturcture, 'numeric');
            app.ThetaLabel = uilabel(app.Panel_Sturcture);
            app.ThetaLabel.Text = 'Theta';
            app.Theta = uieditfield(app.Panel_Sturcture, 'numeric');
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure options';
            app.CheckBox_Atom = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Atom.Text = 'Atom';
            app.CheckBox_Atom.Value = true;
            app.CheckBox_Bond = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Bond.Text = 'Bond';
            app.CheckBox_Bond.Value = true;
            app.CheckBox_Axis = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Axis.Text = 'Axis';
            app.CheckBox_Axis.Value = true;
            app.Button_Generate = uibutton(app.UIFigure, 'push');
            app.Button_Generate.ButtonPushedFcn = createCallbackFcn(app, @Button_GeneratePushed, true);
            app.Button_Generate.Text = 'Generate';
            app.Button_Draw = uibutton(app.UIFigure, 'push');
            app.Button_Draw.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawPushed, true);
            app.Button_Draw.Text = 'Draw';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V 1.1';
            app.Panel_Coupling = uipanel(app.UIFigure);
            app.Panel_Coupling.Title = 'Coupling Model';
            app.FreqModeDropDownLabel = uilabel(app.Panel_Coupling);
            app.FreqModeDropDownLabel.Text = 'Freq. Mode:';
            app.FreqModeDropDown = uidropdown(app.Panel_Coupling);
            app.FreqModeDropDown.Items = {'Numeric', 'Symbolic', ''};
            app.FreqModeDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqModeDropDownValueChanged, true);
            app.FreqModeDropDown.Value = 'Numeric';
            app.CouplingInputLabel = uilabel(app.Panel_Coupling);
            app.CouplingInputLabel.Text = 'Coupling Input:';
            app.EditField_NN = uieditfield(app.Panel_Coupling, 'numeric');
            app.CouplingModelLabel = uilabel(app.Panel_Coupling);
            app.CouplingModelLabel.Text = 'Coupling Model:';
            app.DropDown_Coupling = uidropdown(app.Panel_Coupling);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_NCO(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end